classdef DSE_v302 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiscontinuitySetExtractor3UIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        menu_loadxyz                    matlab.ui.container.Menu
        menu_loadddb                    matlab.ui.container.Menu
        menu_saveddbb                   matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        NormalspacingMenu               matlab.ui.container.Menu
        PersistenceMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool_Clear                  matlab.ui.container.toolbar.PushTool
        PushTool_Load3DP                matlab.ui.container.toolbar.PushTool
        PushTool_LoadProject            matlab.ui.container.toolbar.PushTool
        PushTool_SaveProject            matlab.ui.container.toolbar.PushTool
        LogListBox                      matlab.ui.control.ListBox
        LogListBoxLabel                 matlab.ui.control.Label
        SavefilesPanel                  matlab.ui.container.Panel
        Button_Export_Alldata           matlab.ui.control.Button
        Button_Save_database            matlab.ui.control.Button
        Button_Save_XYZJs               matlab.ui.control.Button
        DensityandprincipalpolesextractionPanel  matlab.ui.container.Panel
        UITable_principalplanes         matlab.ui.control.Table
        NmaxDSEditField                 matlab.ui.control.NumericEditField
        NmaxDSEditFieldLabel            matlab.ui.control.Label
        pushbutton_31editppalpoles      matlab.ui.control.Button
        MinangleEditField               matlab.ui.control.NumericEditField
        MinangleEditFieldLabel          matlab.ui.control.Label
        BinslevelSpinner                matlab.ui.control.Spinner
        BinslevelSpinnerLabel           matlab.ui.control.Label
        pushbutton_3statanalysis        matlab.ui.control.Button
        ClusteranalysisPanel            matlab.ui.container.Panel
        UITable_clusters                matlab.ui.control.Table
        ksigmasEditField                matlab.ui.control.NumericEditField
        ksigmasEditFieldLabel           matlab.ui.control.Label
        Clusters_restore                matlab.ui.control.Button
        Clusters_merge                  matlab.ui.control.Button
        DBSCAN_edit                     matlab.ui.control.Button
        FixnormalvectorCheckBox         matlab.ui.control.CheckBox
        DBSCAN                          matlab.ui.control.Button
        Plots_Panel                     matlab.ui.container.Panel
        pushbutton_plot3dpointsppal     matlab.ui.control.Button
        pushbutton_plotstereopolesppal  matlab.ui.control.Button
        pushbutton_plotdensitypoles     matlab.ui.control.Button
        pushbutton_plotstereopoles      matlab.ui.control.Button
        pushbutton_plot3dpoints         matlab.ui.control.Button
        AssignaDStoeachpointPanel       matlab.ui.container.Panel
        ConeEditField                   matlab.ui.control.NumericEditField
        ConeEditFieldLabel              matlab.ui.control.Label
        Button_assign_ppalpoles         matlab.ui.control.Button
        Normals_Panel                   matlab.ui.container.Panel
        coloreapuntospornormales        matlab.ui.control.Button
        CoplanaritytestSwitchLabel      matlab.ui.control.Label
        HSVCheckBox                     matlab.ui.control.CheckBox
        box_tolerancia                  matlab.ui.control.EditField
        toleranceEditFieldLabel         matlab.ui.control.Label
        CoplanaritytestSwitch           matlab.ui.control.RockerSwitch
        box_nneighbours                 matlab.ui.control.EditField
        knnEditFieldLabel               matlab.ui.control.Label
        pushbutton_2setupplanes         matlab.ui.control.Button
    end


    properties (Access = private)
        data % String en el que guardo los datos que tengo
        gui %
        aux %
        DialogApp % para enviar a otra ventana datos
    end

    methods (Access = private)


        function results = HSV_previo(app)
            results=0;
            % Activo el DSC
            [ dipdir, dip ] = f_cart2clar(app.data.polos_estereo_cartesianas(:,1) ,app.data.polos_estereo_cartesianas(:,2) );
            dipdir=dipdir/180*pi; % la salida hay que convertirla a radianes
            dip=dip/180*pi;

            % Calculo el ángulo alpha
            % alpha es el ángulo desde el OX antihorario hasta la proyección del polo
            alfa=zeros(size(dipdir));
            I=find(dipdir>=0 & dipdir<pi/2); alfa(I)=pi/2-dipdir(I)+pi;
            I=find(dipdir>=pi/2 & dipdir <pi);alfa(I)=pi-(dipdir(I)-pi/2);
            I=find(dipdir>=pi & dipdir<3*pi/2);alfa(I)=pi/2-(dipdir(I)-pi);
            I=find(dipdir>=3*pi/2 & dipdir<2*pi);alfa(I)=7*pi/2-dipdir(I);

            % Ahora represento los polos en mi falsilla con colores
            % z=zeros(length(alfa),1);

            % [x,y] = f_clar2cart(w,b);
            % HSV son los polos con el color HSV
            HSV=zeros(length(alfa),3);
            % Conversión normal de COLTOP
            % HSV(:,1)=(alfa)/(2*pi); % Hue, original
            HSV(:,1)=(alfa-pi/2)/(2*pi); % Hue, lo retraso 90d para que coincida con COLTOP3D
            % regularizo los valores negativos, sumando 1 a los que han
            % ido al cuarto cuadrante y los pongo en positivo
            I=find(HSV(:,1)<0);
            HSV(I,1)=HSV(I,1)+1;
            % Conversión alternativa que repite  el patrón
            %I =find(alfa(:,1)<=pi);
            %HSV(I,1)=alfa(I)/pi;
            %I =find(alfa(:,1)>pi);
            %HSV(I,1)=(alfa(I)-pi)/pi;

            % Calculo la saturación para el dip, que es normal siempre
            HSV(:,2)=dip/(pi/2); % Saturation

            % Pruebo una distribución diferente
            % Los de la primera mitad:
            %I=find(alfa<=pi);
            %HSV(I,1)=(2*alfa(I))/(2*pi); % Hue
            %J=find(alfa>pi);
            %HSV(J,1)=(2*alfa(J)-2*pi)/(2*pi); % Hue
            %HSV(:,2)=dip/(pi/2); % Saturation
            % por si acaso, corrijo estas columnas
            HSV(HSV(:,1)>1,1)=1;
            HSV(HSV(:,2)>1,2)=1;
            HSV(:,3)=1; % Value
            % Calculo los colores según el punto
            rgb = hsv2rgb(HSV)*255;
            % Exportamos los puntos
            XYZ_RGB_HSV=zeros(length(alfa),9);
            XYZ_RGB_HSV(:,1:3)=app.data.P(:,1:3); % las coordenadas
            XYZ_RGB_HSV(:,7:9)=app.data.planos; % las normales
            XYZ_RGB_HSV(:,4:6)=floor(rgb(:,1:3)); % los colores, que los paso a RGB256
            app.data.polos_estereo_cartesianas_HSV_previo=hsv2rgb(HSV);
            app.data.XYZ_RGB_HSV_previo=XYZ_RGB_HSV;
        end

        function results = clear_gui(app)
            results=0;
            % Menús
            app.menu_saveddbb.Enable='off';
            % Cuadrante de normales
            app.pushbutton_2setupplanes.Enable='off';
            app.box_nneighbours.Enable='off';
            app.box_tolerancia.Enable='off';
            app.HSVCheckBox.Enable='off';
            app.coloreapuntospornormales.Enable='off';
            % Cuadrante de plots
            app.pushbutton_plot3dpoints.Enable='off';
            app.pushbutton_plotstereopoles.Enable='off';
            app.pushbutton_plotstereopolesppal.Enable='off';
            app.pushbutton_plotdensitypoles.Enable='off';
            app.pushbutton_plot3dpointsppal.Enable='off';
            app.pushbutton_3statanalysis.Enable='off';
            % Cuadrante de las densidades
            app.BinslevelSpinner.Enable='off';
            app.MinangleEditField.Enable='off';
            app.NmaxDSEditField.Enable='off';
            app.pushbutton_31editppalpoles.Enable='off';
            app.UITable_principalplanes.Enable='off';
            app.UITable_principalplanes.Data=[];
            % Cuadrante asignación de polos
            app.ConeEditField.Enable='off';
            app.Button_assign_ppalpoles.Enable='off';
            % Cuadrante de análisis clúster
            app.UITable_clusters.Enable='off';
            app.UITable_clusters.Data=[];
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.FixnormalvectorCheckBox.Enable='off';
            app.DBSCAN.Enable='off';
            % Panel de guardado
            app.Button_Save_database.Enable='off';
            app.Button_Save_XYZJs.Enable='off';
            app.Button_Export_Alldata.Enable='off';
            % reseteo la información en el log
            
            
            % para mantener el log, S=[a S2]; lo limpio todo
            % a = app.LogListBox.Items; para pillar lo que había en el log
            DateString=char(string(datetime));
            S2 = [DateString, ' - All data was removed.'];
            S3 = [DateString, ' - How about a nice game of chess?'];
            S={S2, S3};
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom');
            app.data.log = S;
            % Mantengo el path para que al cargar la siguiente vez siga en
            % el mismo sitio
            pathname=app.data.pathname;
            % Borro los datos de app.data
            app.data=[];
            app.data.pathname=pathname; % dejo el pathname

            % reinicio el startup
            startupFcn(app);
        end

        function results = enable_fase_1_normales(app)
            results=0;
            app.pushbutton_2setupplanes.Enable='on';
            app.box_nneighbours.Enable='on';
            app.box_tolerancia.Enable='on';
            app.CoplanaritytestSwitch.Enable='on';
            app.menu_saveddbb.Enable='on';
            app.Button_Save_database.Enable='on';
            app.pushbutton_plot3dpoints.Enable='on';
            app.HSVCheckBox.Enable='on';
        end

        function results = save_database(app)
            results=0;
            tic;
            % preparamos las handles a guardar
            app.gui.FileMenu=app.FileMenu;
            app.gui.CleanMenu=app.ClearMenu;
            app.gui.menu_loadxyz=app.menu_loadxyz;
            app.gui.menu_loadddb=app.menu_loadddb;
            app.gui.menu_saveddbb=app.menu_saveddbb;
            app.gui.ToolsMenu=app.ToolsMenu;
            app.gui.NormalspacingMenu=app.NormalspacingMenu;
            % app.gui.SMRstatisticsMenu=app.SMRstatisticsMenu;
            app.gui.PersistenceMenu=app.PersistenceMenu;
            app.gui.HelpMenu=app.HelpMenu;
            app.gui.AboutMenu=app.AboutMenu;
            app.gui.Normals_Panel=app.Normals_Panel;
            app.gui.pushbutton_2setupplanes=app.pushbutton_2setupplanes;
            app.gui.knnEditFieldLabel=app.knnEditFieldLabel;
            app.gui.box_nneighbours=app.box_nneighbours;
            app.gui.toleranceEditFieldLabel=app.toleranceEditFieldLabel;
            app.gui.box_tolerancia=app.box_tolerancia;
            app.gui.CoplanaritytestSwitchLabel=app.CoplanaritytestSwitchLabel;
            app.gui.CoplanaritytestSwitch=app.CoplanaritytestSwitch;
            app.gui.Plots_Panel=app.Plots_Panel;
            app.gui.pushbutton_plot3dpoints=app.pushbutton_plot3dpoints;
            app.gui.pushbutton_plotstereopoles=app.pushbutton_plotstereopoles;
            app.gui.pushbutton_plotstereopolesppal=app.pushbutton_plotstereopolesppal;
            app.gui.pushbutton_plotdensitypoles=app.pushbutton_plotdensitypoles;
            app.gui.pushbutton_plot3dpointsppal=app.pushbutton_plot3dpointsppal;
            % app.gui.ConsoleListBoxLabel=app.ConsoleListBoxLabel;
            app.gui.LogListBox=app.LogListBox;
            app.gui.DensityandprincipalpolesextractionPanel=app.DensityandprincipalpolesextractionPanel;
            app.gui.pushbutton_3statanalysis=app.pushbutton_3statanalysis;
            app.gui.BinslevelSpinnerLabel=app.BinslevelSpinnerLabel;
            app.gui.BinslevelSpinner=app.BinslevelSpinner;
            app.gui.MinangleEditFieldLabel=app.MinangleEditFieldLabel;
            app.gui.MinangleEditField=app.MinangleEditField;
            app.gui.NmaxDSEditFieldLabel=app.NmaxDSEditFieldLabel;
            app.gui.NmaxDSEditField=app.NmaxDSEditField;
            app.gui.pushbutton_31editppalpoles=app.pushbutton_31editppalpoles;
            app.gui.UITable_principalplanes=app.UITable_principalplanes;
            app.gui.Classifythe3DPCwithtthediscontinuitysetsPanel=app.AssignaDStoeachpointPanel;
            app.gui.ConeEditFieldLabel=app.ConeEditFieldLabel;
            app.gui.ConeEditField=app.ConeEditField;
            app.gui.Button_assign_ppalpoles=app.Button_assign_ppalpoles;
            app.gui.SavefilesPanel=app.SavefilesPanel;
            app.gui.Button_Save_database=app.Button_Save_database;
            app.gui.Button_Save_XYZJs=app.Button_Save_XYZJs;
            app.gui.Button_Save_results=app.Button_Export_Alldata;
            app.gui.ClusteranalysisPanel=app.ClusteranalysisPanel;
            app.gui.UITable_clusters=app.UITable_clusters;
            app.gui.ksigmasEditFieldLabel=app.ksigmasEditFieldLabel;
            app.gui.ksigmasEditField=app.ksigmasEditField;
            app.gui.FixnormalvectorCheckBox=app.FixnormalvectorCheckBox;
            app.gui.Button_DBSCAN=app.DBSCAN;
            app.gui.DBSCAN_edit=app.DBSCAN_edit;
            app.gui.Clusters_merge=app.Clusters_merge;
            app.gui.Clusters_restore=app.Clusters_restore;
            if isfield(app.data,'pathname') % & app.data.pathname ~= 0
                pathname = app.data.pathname;
                filename = app.data.filename;
                [filename, pathname] = uiputfile('*.mat', 'Save the ddbb in the file:',[pathname,filename,'.mat']);
            else
                [filename, pathname] = uiputfile('*.mat','Save the ddbb in the file:');
            end
            % guardo en las handles el nombre del archivo y el path
            app.data.pathname = pathname;
            app.data.filename = filename;
            datos=app.data;
            interfaz=app.gui;
            save(strcat(pathname,filename),'datos','interfaz');
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            tiempoempleado=floor(toc);
            S2 = [DateString, ' - The state was saved in a dababase: ',[pathname, filename],'.mat. Total time: ', num2str(tiempoempleado),' seconds'];
            % S = strvcat(a,S2)  ;
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        function output_txt = myfunction(app)
            % Display the position of the data cursor
            pos = get(event_obj,'Position');
            x = pos(1);
            y = pos(2);
            [dipdir, dip]=f_cart2clar(x,y);
            output_txt = {['Dip direction: ',num2str(dipdir,3),'[º]'],...
                ['Dip: ',num2str(dip,3),'[º]']};

            % If there is a Z-coordinate in the position, display it as well
            if length(pos) > 2
                output_txt{end+1} = ['Density: ',num2str(pos(3),4)];
            end
        end
        
        function results = clean_after_density(app)
            results=0;
            if isvariable(app.data, 'polos_estereoppalasignados_afterdbscan_limpio')==1
                app.data.polos_estereoppalasignados_afterdbscan_limpio=[];
                app.data.polos_estereoppalasignados_afterdbscan=[];
                app.data.polos_estereoppalasignados=[];
            end
            
        end


        function results = normalescalculadas_preparaapp(app)
            results=0; % le doy un valor para que no de aviso

            % Parto de que tengo las normales calculadas, que pueden ser
            % importadas del ply o calculadas con este programa

            % Como se que tengo las normales, habilito el color del punto
            % por normal
            app.coloreapuntospornormales.Enable="on";
            app.HSVCheckBox.Enable="on";
            
            % Se convierte el plano a proyección estereográfica
            [planos_estereo]=prepara_estereo(app.data.planos);
            app.data.planos_estereo=planos_estereo;
            % preparo la representación de los planos
            [polos_estereo_polares, polos_estereo_cartesianas]=prepara_representa(planos_estereo);
            app.data.polos_estereo_polares=polos_estereo_polares;
            app.data.polos_estereo_cartesianas=polos_estereo_cartesianas;
            % activamo el análisis estadístico para obtener las familias principales (siguiente paso)
            app.pushbutton_3statanalysis.Enable='on';
            app.BinslevelSpinner.Enable='on';
            app.MinangleEditField.Enable='on';
            app.NmaxDSEditField.Enable='on';
            % activo el botón de representar los polos en stereo
            app.pushbutton_plotstereopoles.Enable='on';

        end
        
        function results = coloreapuntosconnormales(app)
            results=0; % le doy un valor para que no de aviso
             % Preparo la representación de las normales en colores
            if app.HSVCheckBox.Value==1
                tic
                fig = uifigure;
                d=uiprogressdlg(fig,'Title','Computing the HSV colourmap for the normals','Indeterminate','on','Cancelable','on');
                HSV_previo(app); % Para preparar el HSV
                close(fig); % Cierro la figura del diálogo de los colores
                % Ahora guardo en en log que he calculado el color del HSV
                % guardo la información en el log
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                tiempoempleado=floor(toc);
                S2 = [DateString, ' - HSV colours were calculated for the point normals. Total time: ', num2str(tiempoempleado),' seconds'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            
        end
    end
    
    methods (Access = public)
        
        function results = updatefunctionclusters(app,datos)
            % fijo el resulato para no tener el aviso
            results=0;
            % me acaban de pasar los resultados de la edición de clusters
            % en datos, tengo que actualizar la UI y guardarlo todo
            % Primero, hago visible la UI principal
            app.DiscontinuitySetExtractor3UIFigure.Visible="on";
            
            % Guardo el grupo de datos en data
            app.data.puntos_familia_cluster=datos.puntos_familia_cluster;
            app.data.familia_cluster_plano=datos.familia_cluster_plano;
            app.data.puntos_familia_cluster_fullclusters=datos.puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano_fullclusters=datos.familia_cluster_plano_fullclusters;
            app.data.polos_estereoppalasignados_afterdbscan=datos.polos_estereoppalasignados_afterdbscan;
            % app.data=datos;

            % guardo la salida en la tabla de la gui
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % calculo el número de puntos
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Cluster edited manually. Number of points: ',num2str(npuntos)];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end
        
        function results = updatefunctioneditppalpoles(app,datos)
            results=0;
            % Esta función recibe el resultado de editar los polos
            % principales de otra ventana, en la estructura datos

            % Lo primero es activar de nuevo la ventana principal
            app.DiscontinuitySetExtractor3UIFigure.Visible="on";
            

            % Le paso los datos de la edición de los polos principales
            app.data.planos_pples=datos.ppal_poles;

            % introducimos los planos principales en la tabla
            app.UITable_principalplanes.Enable='on';
            app.UITable_principalplanes.Data=app.data.planos_pples;
            % convertimos la notación de Clar a las coordenadas de los polos
            % principales
            dirbuz=app.data.planos_pples(:,1);
            buz=app.data.planos_pples(:,2);
            app.data.polos_pples_cart=zeros(size(app.data.planos_pples));
            [ x,y ] = f_clar2cart( dirbuz,buz );
            app.data.polos_pples_cart(:,1)=x;
            app.data.polos_pples_cart(:,2)=y;
            % guardo la densidad en los nuevos planos pples
            % con esto puedo ver la densidad en 3D
            app.data.polos_pples_cart(:,3)=app.data.planos_pples(:,3);
                       
            % apago el resto de los botones, por si he vuelto atrás
            app.pushbutton_plotstereopolesppal.Enable='off';
            app.pushbutton_plot3dpointsppal.Enable='off';
            % Ahora el paso del dbscan, sólo activo tras el cono
            app.DBSCAN.Enable='off';
            app.UITable_clusters.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.FixnormalvectorCheckBox.Enable='off';
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            % los botones de exportación y guardado
            app.Button_Export_Alldata.Enable='off';
            app.Button_Save_XYZJs.Enable='off';
                        
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Manual edition of the principal poles' ];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Aquí meto el código que se ejecuta cuando se han creado todos
            % los componentes de la GUI
            % Creo la escala de colores
            app.data.base_colores=[
                0	90	1	0	0
                120	90	0	0	1
                240	90	0	1	0
                0	0	1	1	1
                60	90	0	1	0
                180	90	1	0	0
                300	90	0	0	1
                30	45	1	0.5	0
                90	45	0	1	1
                150	45	1	0	1
                210	45	1	1	0
                270	45	0	0.5	1
                330	45	0.5	0	1
                ];
            app.data.k_colores=1.5; % potencia de la distancia
            app.data.q_colores=5; % número de vecinos para calcular color
        end

        % Menu selected function: menu_loadxyz
        function menu_load3DPCSelected(app, event)
            % Compruebo si estoy ya trabajando en alguna carpeta
            if isfield(app.data,'pathname')
                % Tengo una carpeta de trabajo, el menú abre ahí
                [filename, pathname] = uigetfile({'*.txt; *.ply','3DPC Files (*.txt, *.ply)'},'Select the 3D point cloud file. Tip: .txt files load XYZ, .ply can also load normals.',app.data.pathname);
            else
                % Es la primera vez que abro el programa, irá a carpeta por
                % defecto
                [filename, pathname] = uigetfile({'*.txt; *.ply','3DPC Files (*.txt, *.ply)'},'Select the 3D point cloud file. Tip: .txt files load XYZ, .ply can also load normals.');
            end
            
            if filename~=0
                app.data.pathname = pathname;
                [~,nombrearchivo,extension] = fileparts(filename);
                app.data.filename = nombrearchivo;
                app.data.extension = extension;
                % Si es un txt cargo sólo las coordenadas, si es un ply
                % miro si tiene las normales y las cargo directamente.
                switch extension
                    case '.txt'
                        P=load(strcat(pathname, filename));
                        % Por si acaso, guardo las columnas de la 4 al final
                        [npuntos,ncolumnas]=size(P);
                        if ncolumnas >=4
                            app.data.P_extradata=P(:,ncolumnas:4);
                        end
                        % limpio y me quedo sólo con las coordenadas, las tres primeras
                        % columnas
                        P=P(:,1:3);
                        app.data.P = P;
                        % activo la preparación de planos
                        enable_fase_1_normales(app);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Point cloud loaded. File: ',filename,' number of points: ',num2str(npuntos)];
                        % S = strvcat(a,S2)  ;
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    case '.ply'
                        % Para cargar el .ply necesito el Computer Vision
                        % Toolbox, primero veo si lo tiene o no
                        version=ver;
                        toolboxes={version.Name};
                        uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
                        if uso==1
                            % usamos el toolbox para calcular las normales
                            ptCloud=pcread(strcat(pathname, filename));
                            P=ptCloud.Location; % Cargo las coordenadas
                            app.data.P=P; % Guardo las coordenadas de los puntos
                            app.data.ptCloud=ptCloud; % Guardo también la nube para representarla
                            [npuntos,~]=size(P);
                            % compruebo si el .ply tiene las normales
                            [n,~]=size(ptCloud.Normal);
                            switch n
                                case 0
                                    % No hay normales, no lo cargo

                                    % guardo la información en el log
                                    a = app.LogListBox.Items;
                                    DateString=char(string(datetime));
                                    S2 = [DateString, 'I could not load the .ply file because Computer Vision Toolbox is not available.'];
                                    S3 = [DateString, 'Please load an ASCII .txt file.'];
                                    S=[a S2 S3];
                                    app.LogListBox.Items=S;
                                    % fijo la vista del listbox en la última entrada
                                    scroll(app.LogListBox, 'bottom')
                                    app.data.log = S;
                                otherwise
                                    % el valor es no nulo, tengo las
                                    % normales
                                    planos=ptCloud.Normal; %cargo las normales
                                    % Ahora guardo las variables
                                    app.data.planos=planos; % Guardo los planos en las app
                                    % Llamo a la función que se ejecuta
                                    % tras el cálculo de normales
                                    result=normalescalculadas_preparaapp(app);

                                    % Aunque ya tengo las normales, dejo activada el cálculo de normales por si quiero machacarlo
                                    enable_fase_1_normales(app);

                                    % guardo la información en el log
                                    a = app.LogListBox.Items;
                                    DateString=char(string(datetime));
                                    S2 = [DateString, ' - The normals were loaded from the .ply file.'];
                                    S3 = [DateString, ' - Point cloud loaded. File: ',filename,' number of points: ',num2str(npuntos)];
                                    S=[a S2 S3];
                                    app.LogListBox.Items=S;
                                    % fijo la vista del listbox en la última entrada
                                    scroll(app.LogListBox, 'bottom')
                                    app.data.log = S;
                            end

                        else
                            % No tengo el Computer Vision Toolbox, se lo
                            % digo al usuario
                            f = warndlg('Loading .ply files needs Computar Vision Toolbox.','Warning');
                            % guardo la información en el log
                            a = app.LogListBox.Items;
                            DateString=char(string(datetime));
                            S2 = [DateString, 'I could not load the .ply file because Computer Vision Toolbox is not available.'];
                            S3 = [DateString, 'Please load an ASCII .txt file.'];
                            S=[a S2 S3];
                            app.LogListBox.Items=S;
                            % fijo la vista del listbox en la última entrada
                            scroll(app.LogListBox, 'bottom')
                            app.data.log = S;
                        end                    
                end


            end
        end

        % Button pushed function: pushbutton_2setupplanes
        function pushbutton_2setupplanesButtonPushed(app, event)
            % Inputs de la función:
            % - P, las coordenadas de los puntos
            % - npb: tb knn, que es el número de vecinos paraa normales
            % - tolerancia: el valor de eta, si lo necesita
            % Outputs:
            % - planos
            % - planos_estereo
            % - polos_estereo_cartesianas: las xy en el stereonet
            % Preparo una barra de progreso indeterminada
            fig=uifigure;
            d = uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
            drawnow;
            value = app.CoplanaritytestSwitch.Value;
            switch value
                case 'On'
                    % Cálculo con el test de coplanaridad
                    tic;
                    P=app.data.P;
                    npb=str2double(app.box_nneighbours.Value); app.data.npb=npb;
                    tolerancia=str2double(app.box_tolerancia.Value); app.data.tolerancia=tolerancia;
                    % preparamos los planos
                    d = uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
                    [P, calidad_tin, planos]=f_setup_planes_v07(P, npb, tolerancia); % el número de puntos se habrá reducido por el análisis
                    % establecemos el número de puntos en la pantalla
                    [npuntos,~]=size(P);
                    % Actualizo los puntos
                    app.data.P=P;
                    app.data.calidad_tin=calidad_tin;
                    app.data.planos=planos;
                    % convertimos los planos a la proyección estereográfica
                    [planos_estereo]=prepara_estereo(planos);
                    app.data.planos_estereo=planos_estereo;
                    % preparamos la representación de los planos
                    d = uiprogressdlg(fig,'Title','Computing normals without Toolbox','Indeterminate','on','Cancelable','on');
                    [~ , polos_estereo_cartesianas]=prepara_representa(planos_estereo);
                    app.data.polos_estereo_cartesianas=polos_estereo_cartesianas;
                    
                    % activamos el cálculo de planos (siguiente paso)
                    app.pushbutton_3statanalysis.Enable='on';
                    app.BinslevelSpinner.Enable='on';
                    app.MinangleEditField.Enable='on';
                    app.NmaxDSEditField.Enable='on';
                    
                    % activamos el botón de representar los polos en stereo
                    app.pushbutton_plotstereopoles.Enable='on';
                    
                    % registro el tiempo que hemos empleado
                    tiempoempleado=floor(toc);
                    
                    % guardo la información en el log
                    a = app.LogListBox.Items;
                    DateString=char(string(datetime));
                    S2 = [DateString, ' - Normals were calculated,', ' num of neighbours: ',num2str(npb),', tolerance = ',num2str(tolerancia),', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
                    S=[a S2];
                    app.LogListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.LogListBox, 'bottom')
                    app.data.log = S;
                case 'Off'
                    % Cálculo sin el test de coplanaridad
                    tic;
                    P=app.data.P;
                    [npuntos,~]=size(P);
                    npb=str2double(app.box_nneighbours.Value);
                    app.data.npb=npb;
                    % preparamos los planos
                    
                    % Uso o no del Computer Vision Toolbox de MATLAB
                    version=ver;
                    toolboxes={version.Name};
                    uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
                    % En realidad, pccnormals está siempre por lo que se puede ejecutar independientemente de su versión
                    if uso==1
                        % usamos el toolbox para calcular las normales
                        ptCloud=pointCloud(P);
                        d = uiprogressdlg(fig,'Title','Computing normals via Toolbox','Indeterminate','on','Cancelable','on');
                        planos=pcnormals(ptCloud,npb);
                    else
                        % usamos la función nuestra
                        d = uiprogressdlg(fig,'Title','Computing normals without Toolbox','Indeterminate','on','Cancelable','on');
                        [idx, dist, ~, calidad_tin, planos]=f_preparaTIN_v03_nocoplanartest(P, npb);
                        app.data.idx = idx; % En realidad, esto no se usa después
                        app.data.dist = dist; % Tampoco se usa después
                        app.data.calidad_tin=calidad_tin; % No se usa
                    end
                    
                    % Ahora guardo las variables
                    app.data.planos=planos; % Guardo los planos en las app

                    % Tengo los planos calculados. Llamo a la función que
                    % prepara la GUI para el siguiente paso
                    results=normalescalculadas_preparaapp(app);

                    % Ya terminado todo, guardamos el log
                    if uso==1
                        % hemos usado el toolbox para calcular las normales
                        tiempoempleado=floor(toc);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Normals calculated with Computer Vision Toolbox, num of neighbours: ',num2str(npb),', n points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' s.'];
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    else
                        % No hemos usado el toolbox sino que hemos
                        % calculado con nuestra función de normales
                        % usamos la función nuestra
                        tiempoempleado=floor(toc);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Normals calculated without compalarity test, num of neighbours: ',num2str(npb),', n points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' s.'];
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    end
            end %Fin del switch
            % En cualquiera de los casos, ya tengo las normales y habilito
            % la representación de puntos por colores de normales
            app.coloreapuntospornormales.Enable='on';
            app.HSVCheckBox.Enable='on';
            % Fin del cálculo de las normales
            close(fig);
        end

        % Button pushed function: pushbutton_plot3dpoints
        function pushbutton_plot3dpointsButtonPushed(app, event)
            P=app.data.P;
            version=ver;
            toolboxes={version.Name};
            uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if uso==1
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Tengo el Toolbox para nubes de puntos y puedo usarlo
                    % Compruebo si tengo la nube del ply
                    if isfield(app.data,'ptCloud')
                        h=figure;
                        pcshow(app.data.ptCloud);
                        h.Name='3D Point Cloud from .ply'
                    else
                        ptCloud=pointCloud(P(:,1:3));
                        h=figure;
                        pcshow(ptCloud);
                        h.Name='3D Point Cloud from .txt';
                    end
                    % si he calculado los vectores normales represento el
                    % HSV
                    switch app.pushbutton_3statanalysis.Enable
                        case 'on'
                            % Represento la nube en el HSV
                            if isfield(app.data,'XYZ_RGB_HSV_previo')==1
                                ptCloud=pointCloud(app.data.XYZ_RGB_HSV_previo(:,1:3));
                                ptCloud.Color=uint8(app.data.XYZ_RGB_HSV_previo(:,4:6));
                                ptCloud.Normal=app.data.XYZ_RGB_HSV_previo(:,7:9);
                                h=figure;
                                pcshow(ptCloud);
                                pcwrite(ptCloud,[app.data.pathname, app.data.filename, ' XYZ-HSV-normals.ply'],'PLYFormat','binary');
                                h.Name='3D Point Cloud coloured using the HSV colourmap';
                                % Ahora dibujo el estereograma con colores
                                % Dibujo la falsilla para comparar
                                h=figure;
                                falsilla_riquelme;
                                h.Color=[1 1 1];
                                axis('square','equal');
                                polos_x=app.data.polos_estereo_cartesianas(:,1);
                                polos_y=app.data.polos_estereo_cartesianas(:,2);
                                polos_z=zeros(size(polos_x));
                                polos_z(:)=-0.1;
                                polos_colores=app.data.polos_estereo_cartesianas_HSV_previo;
                                scatter3(polos_x,polos_y,polos_z,10,polos_colores,'filled');
                                % preparo el puntero
                                dcm = datacursormode(gcf);
                                datacursormode on;
                                set(dcm,'updatefcn',@myupdatefcn)
                                h.Name='HSV scheme (dip direction - dip) of the normal vector poles';
                            end
                    end
                else
                    % No tengo ese toolbox y empleo la programación antigua
                    h=figure;
                    h.Color=[1 1 1];
                    scatter3(P(:,1),P(:,2),P(:,3),10,P(:,3),'filled');
                    title(['3D Points: ',num2str(length(P)),' points']);
                    axis('equal') ,xlabel('axis X'),ylabel('axis Y'),zlabel('axis Z');
                end
            end
            [npuntos,~]=size(P);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - 3D point cloud plotted, number of points: ',num2str(npuntos)];
            % S = strvcat(a,S2)  ;
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Value changed function: BinslevelSpinner
        function BinslevelSpinnerValueChanged(app, event)
            value = app.BinslevelSpinner.Value;
            if value<=0
                app.BinslevelSpinner.Value=6;
            end
        end

        % Button pushed function: pushbutton_plotstereopoles
        function pushbutton_plotstereopolesButtonPushed(app, event)
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            h=figure;
            % wulff;
            falsilla_riquelme;
            h.Color=[1 1 1];
            axis('square','equal');
            % scatter(polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),calidad_tin(:,1));
            z=zeros(size(polos_estereo_cartesianas(:,1)));
            z(:,1)=-0.1;
            scatter3(polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),z,1);
            h.Name=['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_estereo_cartesianas)),' points'];

            % Guardo la imagen en un fichero
            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            % Ya puedo guardar la imagen
            name_png = [pathname,filename, ' - Poles - ', '.png']; saveas(gcf, name_png);
            name_pdf = [pathname, filename, ' - Poles - ', '.pdf']; saveas(gcf, name_pdf);

            % preparo el puntero para que muestre la orientación del punto
            dcm = datacursormode(gcf);
            datacursormode on;
            set(dcm,'updatefcn',@myupdatefcn)
        end

        % Button pushed function: pushbutton_3statanalysis
        function pushbutton_3statanalysisButtonPushed(app, event)
            tic;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            % sectorización de la malla para el kde
            nsec=2^app.BinslevelSpinner.Value;
            angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            % guardo los datos con los que calculamos
            app.data.nsec=nsec;
            app.data.angulovpples=angulovpples;
            % preparamos la función de densidad con el kde
            [polos_pples, polos_pples_cart,density, X, Y]=f_prepara_planos_kde(polos_estereo_cartesianas,nsec, angulovpples, maxpples);
            app.data.polos_pples_cart=polos_pples_cart;
            app.data.X=X;
            app.data.Y=Y;
            app.data.density=density;
            % buscamos los planos principales
            [ planos_pples ] = f_principal_planes(polos_pples);
            app.data.planos_pples=planos_pples;
            % introducimos los planos principales en la tabla
            app.UITable_principalplanes.Enable='on';
            app.UITable_principalplanes.Data=planos_pples;
            % set visible the uipanel for assignment poles to the points
            app.Button_assign_ppalpoles.Enable='on';
            app.ConeEditField.Enable='on';
            % activate the principal poles editor
            app.pushbutton_31editppalpoles.Enable='on';
            % make visible the plot principal poles button
            app.pushbutton_plotdensitypoles.Enable='on';
            
            % apago el resto de los botones, por si he vuelto atrás
            app.pushbutton_plotstereopolesppal.Enable='off';
            app.pushbutton_plot3dpointsppal.Enable='off';
            % Ahora el paso del dbscan, sólo activo tras el cono
            app.DBSCAN.Enable='off';
            app.UITable_clusters.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.FixnormalvectorCheckBox.Enable='off';
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            % los botones de exportación y guardado
            app.Button_Export_Alldata.Enable='off';
            app.Button_Save_XYZJs.Enable='off';
            
            % Guardo la info en el cuadro de texto
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Statistic calculation, Nº of bins: ',num2str(nsec),', min angle btwn ppal poles: ',num2str(angulovpples),', max nº of ppal poles: ',num2str(maxpples),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Value changing function: BinslevelSpinner
        function BinslevelSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if app.BinslevelSpinner.Value<=0
                app.BinslevelSpinner.Value=6;
            end
        end

        % Button pushed function: pushbutton_plotdensitypoles
        function pushbutton_plotdensitypolesButtonPushed(app, event)
            tic;
            polos_pples_cart=app.data.polos_pples_cart;
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            % representamos la salida
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=polos_pples_cart(1:kk,3)'+0.1;
            end
            maxd=max(max(density)); mind=min(min(density));
            numberisolines=80; % número de isoloníneas
            % isolines=(maxd-mind)/numberisolines;

            filename = app.data.filename;
            h=figure('name',[filename, ' density function']);
            % wulff;
            falsilla_riquelme;
            h.Color=[1 1 1];
            hold on; contour3(X,Y,density,numberisolines); % dibuja con contornos
            axis ([-1 1 -1 1]); axis('square','equal');
            g=gca;
            for ii=1:kk
                text(g,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18, 'EdgeColor','k','BackgroundColor','w');
            end
            scatter3(polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3), tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            g.Title.String={['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'],''}; % Le añado una línea

            % preparo el puntero
            dcm = datacursormode(gcf);
            datacursormode on;
            set(dcm,'updatefcn',@myupdatefcn)

            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end

            % Guardo la imagen
            name_png = [pathname,filename, ' - Density', '.png'];
            name_pdf = [pathname,filename, ' - Density', '.pdf'];
            % export_fig(name, '-m2.5', '-transparent');
            saveas(gcf, name_png);
            saveas(gcf, name_pdf);

            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - The poles density was plotted. Total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: pushbutton_31editppalpoles
        function pushbutton_31editppalpolesButtonPushed(app, event)
            % Edición manual de los polos principales
            
            % En primer lugar, deshabilito la ventana principal para ver
            % sólo el editor de polos principales
            app.DiscontinuitySetExtractor3UIFigure.Visible="off";

            % Ahora preparo los datos a enviar al editor de polos
            % Lo meto todo en la estructura datos
            datos.ppal_poles=app.data.planos_pples; 
            datos.polos_pples_cart=app.data.polos_pples_cart;
            datos.X=app.data.X;
            datos.Y=app.data.Y;
            datos.density=app.data.density;
            datos.polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            datos.P = app.data.P;

            % Si los planos principales todavía no tienen el % de puntos asignados, la matriz tendrá dimensión 3 filas.
            % Regularizo esa matriz para que tenga dimensión 4 columnas.
            [n,c]=size(datos.ppal_poles);
            if c==3
                datos.ppal_poles=[datos.ppal_poles zeros(n,1)];
            end

            % Finalmente, llamo a la aplicación
            app.DialogApp=gui_ppalpoleseditor_v07(app,datos);         
        end

        % Button pushed function: Button_assign_ppalpoles
        function Button_assign_ppalpolesPushed(app, event)
            % Inputs
            % - P
            % - cone: apertura del cono
            % - polos_estereo_cartesianas
            % - polos_pples_cart
            % Outputs
            % - P
            % - planos
            % - planos_pples
            % - polos_estereoppalasignados
            % - puntos_ppalasignados
            % Asignación de polos principales a los puntos
            tic; % Inicio un contador para ver el tiempo empleado
            cone=app.ConeEditField.Value;
            cone=cone/180*pi;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            polos_pples_cart=app.data.polos_pples_cart;
            P=app.data.P;
            [polos_estereoppalasignados, puntos_ppalasignados] = f_setppal2planes_v02( cone, polos_estereo_cartesianas, polos_pples_cart ,P);
            app.data.polos_estereoppalasignados=polos_estereoppalasignados;
            app.data.puntos_ppalasignados=puntos_ppalasignados;
            app.pushbutton_plotstereopolesppal.Enable='on';
            app.pushbutton_plot3dpointsppal.Enable='on';
            % guardo los datos con los que calculamos
            app.data.cone=cone;
            % actualizo el numero de puntos que tengo
            % I es el índice de los puntos con cluster asignado
            I=find(polos_estereoppalasignados(:,3)>0);
            % actualizo los datos a aquellos con cluster
            polos_estereo_cartesianas=polos_estereo_cartesianas(I,:);
            P=P(I,:);
            planos=app.data.planos(I,:);
            % puntos_ppalasignados=puntos_ppalasignados(I,:);
            % [npuntos,~]=size(I);
            % actualizo el número de puntos que representan los polos principales
            planos_pples=app.data.planos_pples;
            [n,~]=size(P); % número de puntos totales
            [nf,~]=size(planos_pples); % número de familias que hay
            for i=1:nf
                % calculo el % de puntos que hay y lo pongo en la cuarta columna
                [npuntos,~]=size(polos_estereoppalasignados(polos_estereoppalasignados(:,3)==i));
                planos_pples(i,4)=npuntos/n*100;
            end
            app.data.planos_pples=planos_pples;

            
            % Aquí ya tengo los polos y puntos clasificados
            % Ahora preparo representaciones para colores y tal
            
            % Activo el DSC
            [ dipdir, dip ] = f_cart2clar(polos_estereo_cartesianas(:,1) ,polos_estereo_cartesianas(:,2) );
            dipdir=dipdir/180*pi;
            dip=dip/180*pi;

            % Calculo el ángulo alpha
            % alpha es el ángulo desde el OX antihorario hasta la proyección del polo
            alfa=zeros(size(dipdir));
            I=find(dipdir>=0 & dipdir<pi/2); alfa(I)=pi/2-dipdir(I)+pi;
            I=find(dipdir>=pi/2 & dipdir <pi);alfa(I)=pi-(dipdir(I)-pi/2);
            I=find(dipdir>=pi & dipdir<3*pi/2);alfa(I)=pi/2-(dipdir(I)-pi);
            I=find(dipdir>=3*pi/2 & dipdir<2*pi);alfa(I)=7*pi/2-dipdir(I);

            % Ahora represento los polos en mi falsilla con colores
            % z=zeros(length(alfa),1);

            % [x,y] = f_clar2cart(w,b);
            HSV=zeros(length(alfa),3);
            HSV(:,1)=(alfa)/(2*pi); % Hue
            HSV(:,2)=dip/(pi/2); % Saturation
            % por si acaso, corrijo estas columnas
            HSV(HSV(:,1)>1,1)=1;
            HSV(HSV(:,2)>1,2)=1;
            HSV(:,3)=1; % Value
            % Calculo los colores según el punto
            rgb = hsv2rgb(HSV)*255;
            % Exportamos los puntos
            XYZ_RGB_HSV=zeros(length(alfa),9);
            XYZ_RGB_HSV(:,1:3)=P(:,1:3); % las coordenadas
            XYZ_RGB_HSV(:,7:9)=planos; % las normales
            XYZ_RGB_HSV(:,4:6)=floor(rgb(:,1:3)); % los colores, que los paso a RGB256
            app.data.polos_estereo_cartesianas_HSV=hsv2rgb(HSV);
            app.data.XYZ_RGB_HSV=XYZ_RGB_HSV;

            % Uso o no del Computer Vision Toolbox de MATLAB
            version=ver;
            toolboxes={version.Name};
            uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if uso==1
                % La versión 6.2 del CVTB es la que incluyó las funciones
                % de nubes de puntos
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Ya puedo generar el archivo de nube de MATLAB
                    ptCloud_HSV=pointCloud(P(:,1:3));
                    ptCloud_HSV.Normal=planos;
                    ptCloud_HSV.Color=uint8(rgb);
                    app.data.ptCloud_HSV=ptCloud_HSV;
                    % tengo que generar los colores
                    ptCloud=pointCloud(P(:,1:3));
                    % Hay que generar los colores por familias
                    % comprobamos si hay alguna familia asignada
                    if max(puntos_ppalasignados(:,4))>0
                        I=find(puntos_ppalasignados(:,4)>0);
                        puntos_ppalasignados=puntos_ppalasignados(I,:);
                    end
                    % Le meto el índice de la familia asignada en la
                    % intensidad
                    ptCloud.Intensity=puntos_ppalasignados(:,4);
                    % Guardo la nube de los colores
                    app.data.ptCloud=ptCloud;
                else
                    % No lo tengo y no guardo nada
                end
            end

            % activamos la fase de análisis cluster
            app.DBSCAN.Enable='on';
            app.FixnormalvectorCheckBox.Enable='on';
            app.Button_Save_XYZJs.Enable='on';
            
            % Ahora el paso del dbscan, sólo activo tras el cono
            app.UITable_clusters.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            % los botones de exportación y guardado
            app.Button_Export_Alldata.Enable='off';
            
            
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Ppal poles assignment to each point, cone angle from the axis to the generatrix: ',num2str(cone/pi*180,2),'°. N of points: ',num2str(size(P,1)),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: pushbutton_plotstereopolesppal
        function pushbutton_plotstereopolesppalButtonPushed(app, event)
            % Primero vamos a ver qué campos existen
            if app.DBSCAN_edit.Enable=='on'
                % Ya hemos hecho el dbscan y represento con los limpios
                if isfield(app.data,'polos_estereoppalasignados_afterdbscan_limpio')==1 && isempty(app.data.polos_estereoppalasignados_afterdbscan_limpio)==0
                    A=app.data.polos_estereoppalasignados_afterdbscan_limpio;
                else
                    if isfield(app.data,'polos_estereoppalasignados_afterdbscan')==1 && isempty(app.data.polos_estereoppalasignados_afterdbscan)==0
                        A=app.data.polos_estereoppalasignados_afterdbscan;
                    else
                        if isfield(app.data,'polos_estereoppalasignados')==1 && isempty(app.data.polos_estereoppalasignados)==0
                            A=app.data.polos_estereoppalasignados;
                        else
                            % Ya no hay mas casos, dejo A vacío para que falle
                        end
                    end
                end
            else
                % Todavía no hemos hecho el DBSCAN y tengo que usar el que
                % sale de la asignación del cono
                A=app.data.polos_estereoppalasignados;
            end
            % I=find(A(:,3)>0);
            A=A(A(:,3)>0,:);
            % represento las estereográficas
            h=figure;
            % dibujamos la falsilla
            falsilla_riquelme;
            h.Color=[1 1 1];
            axis('square','equal');
            scatter(A(:,1),A(:,2),5,A(:,3));
            colormap jet
            h.Name=['Stereographic Projection Assigned Principal Poles: ',num2str(length(A)),' points'];

            % Guardo la imagen en un fichero
            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            % Ya puedo guardar la imagen
            name_png = [pathname, filename, ' - Assigned Poles', '.png']; saveas(gcf, name_png);
            name_pdf = [pathname, filename, ' - Assigned Poles', '.pdf']; saveas(gcf, name_pdf);

            % preparo el puntero para que muestre la orientación del punto
            dcm = datacursormode(gcf);
            datacursormode on;
            set(dcm,'updatefcn',@myupdatefcn)

        end

        % Button pushed function: pushbutton_plot3dpointsppal
        function pushbutton_plot3dpointsppalButtonPushed(app, event)
            puntos_ppalasignados=app.data.puntos_ppalasignados;
            I=find(puntos_ppalasignados(:,4)>0);
            A=puntos_ppalasignados(I,:);
            % Uso o no del Computer Vision Toolbox de MATLAB
            version=ver;
            toolboxes={version.Name};
            uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if uso==1
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Tengo el Toolbox para nubes de puntos y puedo usarlo
                    
                    % Este código es para mostrar la nube clasificada
                    % % Dibujo la falsilla para comparar
                    % h=figure;
                    % falsilla_riquelme;
                    % h.Color=[1 1 1];
                    % axis('square','equal');
                    % % Nota que I son los índices de los puntos con clusters asignados,
                    % % hay que filtrar esto
                    % polos_x=app.data.polos_estereo_cartesianas(I,1);
                    % polos_y=app.data.polos_estereo_cartesianas(I,2);
                    % polos_z=zeros(size(polos_x));
                    % polos_z(:)=-0.1;
                    % % polos_colores=app.data.polos_estereo_cartesianas_HSV;
                    % scatter3(polos_x,polos_y,polos_z,[],polos_colores,'filled');
                    % % title(['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_z)),' points']);
                    % % xlabel('axis X'); ylabel('axis Y');
                    % h.Name='HSV colour scheme (dip direction - dip) of the normal vector poles';
                    % % Ahora dibujo la nube con HSV
                    % h=figure; pcshow(app.data.ptCloud_HSV);
                    % h.Name='3D point cloud coloured according the HSV scheme';
                    % Ahora dibujo la nube con RGB
                    % ptCloud tiene las coordenadas con el índice de la
                    % familia en Intensity
                    h=figure; 
                    pcshow(app.data.ptCloud);
                    h.Name='3D point cloud coloured using one colour for each DS';
                else
                    h=figure; h.Color=[1 1 1];
                    scatter3(A(:,1),A(:,2),A(:,3),20,A(:,4),'filled');
                    h.Name=['3D Points Family assigned: ',num2str(length(A)),' points'];
                    axis equal ,xlabel('axis X'),ylabel('axis Y'),zlabel('axis Z');
                end
            end
        end

        % Button pushed function: DBSCAN
        function DBSCANPushed(app, event)
            % cálculo de los clústers sin mínimo de puntos por cluster. Eso se asigna
            % con el editor de clústers
            % Inputs
            % - puntos_ppalasignados
            % - planos_pples
            % - polos_estereoppalasignados
            % Outputs
            % - familia_cluster_plano_fullclusters
            % - puntos_familia_cluster_fullclusters
            % - familia_cluster_plano
            % - puntos_familia_cluster
            % - polos_estereoppalasignados
            tic % para tener el tiempo que tardo en hacer el análisis cluster, se pondrá en el log
            % guardo los datos con los que he calculado los clusters
            ppcluster=4; % Es el número mínimo de puntos que tienen que tener un cluster para ser considerado como tal
            app.data.ppcluster=ppcluster;
            puntos_ppalasignados=app.data.puntos_ppalasignados;
            planos_pples=app.data.planos_pples;
            polos_estereoppalasignados=app.data.polos_estereoppalasignados;
            vnfamilia=app.FixnormalvectorCheckBox.Value; app.data.vnfamilia = vnfamilia;
            % ksigmas=app.ksigmasEditField.Value; app.data.ksigmas = ksigmas;
            [ puntos_familia_cluster_fullclusters, familia_cluster_plano_fullclusters, polos_estereoppalasignados_afterdbscan ] = f_ppal2cluster_v10( puntos_ppalasignados, planos_pples, ppcluster,vnfamilia, polos_estereoppalasignados);
            % Guardamos en las app la clasificación con todos los clusters
            % la salida es con todos los clusters sin filtrar, luego los guardamos en
            % los datos globales (app) para utilizarlos siempre
            app.data.puntos_familia_cluster_fullclusters=puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano_fullclusters=familia_cluster_plano_fullclusters;
            % a partir de aquí, utilizamos siempre estas dos salidas, que se irán
            % modificando al editar el número mínimo de puntos por cluster
            app.data.puntos_familia_cluster=puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano=familia_cluster_plano_fullclusters;
            % Además, hay que dejar las variables en el workspace local para seguir
            % trabajando
            % puntos_familia_cluster=puntos_familia_cluster_fullclusters;
            familia_cluster_plano=familia_cluster_plano_fullclusters;
            % Guardo los polos en cartesianas tras el dbscan
            app.data.polos_estereoppalasignados_afterdbscan=polos_estereoppalasignados_afterdbscan;
            % Preparo los siguientes botones
            app.UITable_clusters.Enable='on';

            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;

            % activo la edición de clusters
            app.DBSCAN_edit.Enable='on';
            app.Clusters_merge.Enable='on';
            app.Clusters_restore.Enable='on';
            app.ksigmasEditField.Enable='on';
            % set(app.popupmenu_save_output.Enable='on';
            app.Button_Export_Alldata.Enable='on';
            % calculo el número de puntos tras el análisis cluster
            [npuntos,~]=size(puntos_familia_cluster_fullclusters);
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            if vnfamilia ==1
                S3 = 'Cl`s planes are oriented with its ppal pole normal vector.';
            else
                S3 = 'Cl`s planes are oriented with its best fit plane.';
            end
            S2 = [DateString, ' - Cluster analysis. ',S3,', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clear_gui(app);
        end

        % Value changed function: CoplanaritytestSwitch
        function CoplanaritytestSwitchValueChanged(app, event)
            value = app.CoplanaritytestSwitch.Value;
            switch value
                case 'On'
                    app.box_tolerancia.Enable='on';
                case 'Off'
                    app.box_tolerancia.Enable='off';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            gui_about;
        end

        % Button pushed function: DBSCAN_edit
        function DBSCAN_editPushed(app, event)
            % Desactivo la UI principal para trabajar sólo con el editor
            app.DiscontinuitySetExtractor3UIFigure.Visible="off";
            % Los datos que necesito están en app.data, sólo necesito
            % algunos. Guardo en una variable llamada datos lo que hace
            % falta para editar los clusters
            datos.puntos_familia_cluster=app.data.puntos_familia_cluster;
            datos.familia_cluster_plano=app.data.familia_cluster_plano;
            datos.puntos_familia_cluster_fullclusters=app.data.puntos_familia_cluster_fullclusters;
            datos.familia_cluster_plano_fullclusters=app.data.familia_cluster_plano_fullclusters;
            datos.polos_estereoppalasignados_afterdbscan=app.data.polos_estereoppalasignados_afterdbscan;

            % Llamo al editor de clusters y le paso la estructura datos
            app.DialogApp=gui_clusterseditor_v03(app, datos);

        end

        % Button pushed function: Clusters_merge
        function Clusters_mergePushed(app, event)
            % value = app.Clusters_merge.Value;
            % cálculo de los clústers sin mínimo de puntos por cluster. Eso se asigna
            % con el editor de clústers
            tic % para tener el tiempo que tardo en hacer el análisis cluster, se pondrá en el log
            % guardo los datos con los que he calculado los clusters
            app.data.vnfamilia=app.FixnormalvectorCheckBox.Value;
            app.data.ksigmas=app.ksigmasEditField.Value;
            [app.data.familia_cluster_plano] = f_merge_clusters_v01(app.data.ksigmas, app.data.vnfamilia, app.data.puntos_familia_cluster, app.data.familia_cluster_plano);% Guardamos en las handles la clasificación con todos los clusters
            % la salida es con todos los clusters sin filtrar, luego los guardamos en
            % Pongo la matriz de familia cluster planos en la interfaz
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % calculo el número de puntos tras el análisis cluster
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            if app.data.vnfamilia ==1
                S3 = 'Cl`s planes are oriented with its ppal pole normal vector.';
            else
                S3 = 'Cl`s planes are oriented with its best fit plane.';
            end
            S2 = [DateString, ' - Coplanar clusters were merged. ',S3,', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Clusters_restore
        function Clusters_restoreButtonPushed(app, event)
            % cargo los datos
            % guardo la situación actual de los clusters en la tabla de la gui
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % guardo la información en las handles
            app.data.puntos_familia_cluster=app.data.puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano=app.data.familia_cluster_plano_fullclusters;
            % calculo el número de puntos
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Cluster restored without minimum number of points per cluster. N of points: ',num2str(npuntos)];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Button_Save_XYZJs
        function Button_Save_XYZJsPushed(app, event)
            tic % pongo el contador a 0
            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') % & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') % & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            puntos_ppalasignados=app.data.puntos_ppalasignados;
            % comprobamos si hay alguna familia asignada
            if max(puntos_ppalasignados(:,4))>0
                I=find(puntos_ppalasignados(:,4)>0);
                puntos_ppalasignados=puntos_ppalasignados(I,:);
            end
            im=puntos_ppalasignados(:,4); %guardamos las familias
            % transforma escala de intensidades (0 a n) a RGB (0 a 1)  from http://www.alecjacobson.com/weblog/?p=1655
            n = size(unique(reshape(im,size(im,1)*size(im,2),size(im,3))),1);
            im= double(im);
            im=im*255/n;
            im=uint8(im);
            rgb = ind2rgb(im,jet(255));
            % Crear nube de puntos con atributo RGB (0 a 255)
            colores=zeros(size(puntos_ppalasignados,1),3);
            colores(:,1)=reshape(round(rgb(:,:,1)*255),[],1);
            colores(:,2)=reshape(round(rgb(:,:,2)*255),[],1);
            colores(:,3)=reshape(round(rgb(:,:,3)*255),[],1);
            % IDEA IMPORTANTE, METER EL JS COMO INTENSITY Y PASAR DEL TXT OUTPUT
            % Guardo únicamente el XYZ_Js, el RGB no sirve para nada en CC
            XYZ_JS=puntos_ppalasignados(:,1:3);
            XYZ_JS(:,4)=puntos_ppalasignados(:,4); % le pongo también las familias para que el escalar las pueda representar
            [~]=f_savematrix([pathname,filename, ' XYZ-JS-early_classification.txt'], XYZ_JS);
            % Ahora guardo el archivo del pointcloud
            XYZ_RGB_HSV=app.data.XYZ_RGB_HSV;
            % Uso o no del Computer Vision Toolbox de MATLAB
            version=ver;
            toolboxes={version.Name};
            uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if uso==1
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Tengo el Toolbox para nubes de puntos y puedo usarlo
                    % guardamos un archivo ply
                    app.data.ptCloud_RGB=pointCloud(puntos_ppalasignados(:,1:3));
                    app.data.ptCloud_RGB.Color=uint8(colores);
                    % Si lo quiero guardar hago esto
                    % pcwrite(ptCloud_early,[pathname, filename, ' XYZ-RGB-early_classification.ply'],'PLYFormat','binary');
                    % Si lo quiero dibujar hago esto:
                    % figure; pcshow(ptCloud_early);

                    % Preparo la nube con el HSV y la guardo
                    app.data.ptCloud_HSV=pointCloud(XYZ_RGB_HSV(:,1:3));
                    app.data.ptCloud_HSV.Normal=XYZ_RGB_HSV(:,7:9);
                    app.data.ptCloud_HSV.Color=uint8(XYZ_RGB_HSV(:,4:6));
                    pcwrite(app.data.ptCloud_HSV,[pathname, filename, ' XYZ-HSV-early_classification.ply'],'PLYFormat','binary');
                else
                    % No lo tengo y empleo la programación antigua
                end
            end
            % guardo la información en el log
            tiempo=floor(toc);
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - An early classification was saved: ',filename, ' XYZ-JS-early_classification.txt. ', num2str(tiempo), ' s.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Menu selected function: menu_loadddb
        function menu_loadddbMenuSelected(app, event)
            warning off;
            tic;
            [filename, pathname] = uigetfile('*.mat','Abra el archivo de datos');
            if filename~=0
                S= load(strcat(pathname,filename));
                % Ahora nos quedamos sólo con el nombre del archivo
                [~,filename,~] = fileparts(filename);
                % Pongo el nombre del archivo en la ventana
                app.data.filename=filename;
                app.data.pathname=pathname;
                % El directorio podría haber cambiado, así que dejamos el
                % directorio desde el que han cargado el archivo
                S.datos.pathname=pathname;
                app.data=S.datos;
                % Restauramos la configuración
                % Aquí únicamente establecemos si el menú está activo o no
                app.FileMenu.Enable=S.interfaz.FileMenu.Enable;
                app.ClearMenu.Enable=S.interfaz.CleanMenu.Enable;
                app.menu_loadxyz.Enable=S.interfaz.menu_loadxyz.Enable;
                app.menu_loadddb.Enable=S.interfaz.menu_loadddb.Enable;
                app.menu_saveddbb.Enable=S.interfaz.menu_saveddbb.Enable;
                app.ToolsMenu.Enable=S.interfaz.ToolsMenu.Enable;
                app.NormalspacingMenu.Enable=S.interfaz.NormalspacingMenu.Enable;
                app.PersistenceMenu.Enable=S.interfaz.PersistenceMenu.Enable;
                % app.SMRstatisticsMenu.Enable=S.interfaz.SMRstatisticsMenu.Enable;
                app.HelpMenu.Enable=S.interfaz.HelpMenu.Enable;
                app.AboutMenu.Enable=S.interfaz.AboutMenu.Enable;

                % Al cargar la nube, ya se puede representar
                app.pushbutton_plot3dpoints.Enable=S.interfaz.pushbutton_plot3dpoints.Enable;

                % 2 activamos el panel de normales, si procede
                app.pushbutton_2setupplanes.Enable=S.interfaz.pushbutton_2setupplanes.Enable;
                % Si el botón de normales está activado, lo estarán los
                % colores para las normales
                app.HSVCheckBox.Enable=S.interfaz.pushbutton_2setupplanes.Enable;
                app.knnEditFieldLabel.Enable=S.interfaz.knnEditFieldLabel.Enable;
                app.box_nneighbours.Enable=S.interfaz.box_nneighbours.Enable;app.box_nneighbours.Value=S.interfaz.box_nneighbours.Value;
                app.toleranceEditFieldLabel.Enable=S.interfaz.toleranceEditFieldLabel.Enable;
                app.box_tolerancia.Enable=S.interfaz.box_tolerancia.Enable;app.box_tolerancia.Value=S.interfaz.box_tolerancia.Value;
                % Los test de coplanaridad:
                app.CoplanaritytestSwitchLabel.Enable=S.interfaz.CoplanaritytestSwitchLabel.Enable;
                app.CoplanaritytestSwitch.Enable=S.interfaz.CoplanaritytestSwitch.Enable;app.CoplanaritytestSwitch.Value=S.interfaz.CoplanaritytestSwitch.Value;
                % Si hemos calculado las normales, puedo representar los
                % polos
                app.pushbutton_plotstereopoles.Enable=S.interfaz.pushbutton_plotstereopoles.Enable;

                % 3 Activamos en análisis de densidades
                app.UITable_principalplanes.Enable=S.interfaz.UITable_principalplanes.Enable;app.UITable_principalplanes.Data=S.interfaz.UITable_principalplanes.Data;
                app.pushbutton_3statanalysis.Enable=S.interfaz.pushbutton_3statanalysis.Enable;
                app.pushbutton_31editppalpoles.Enable=S.interfaz.pushbutton_31editppalpoles.Enable;
                app.BinslevelSpinnerLabel.Enable=S.interfaz.BinslevelSpinnerLabel.Enable;
                app.BinslevelSpinner.Enable=S.interfaz.BinslevelSpinner.Enable;app.BinslevelSpinner.Value=S.interfaz.BinslevelSpinner.Value;
                app.MinangleEditFieldLabel.Enable=S.interfaz.MinangleEditFieldLabel.Enable;
                app.MinangleEditField.Enable=S.interfaz.MinangleEditField.Enable;app.MinangleEditField.Value=S.interfaz.MinangleEditField.Value;
                app.NmaxDSEditFieldLabel.Enable=S.interfaz.NmaxDSEditFieldLabel.Enable;
                app.NmaxDSEditField.Enable=S.interfaz.NmaxDSEditField.Enable;app.NmaxDSEditField.Value=S.interfaz.NmaxDSEditField.Value;
                % puedo representar la densidad
                app.pushbutton_plotdensitypoles.Enable=S.interfaz.pushbutton_plotdensitypoles.Enable;

                % 4 Activamos la asignación de polos a polo principal
                app.Button_assign_ppalpoles.Enable=S.interfaz.Button_assign_ppalpoles.Enable;
                app.ConeEditFieldLabel.Enable=S.interfaz.ConeEditFieldLabel.Enable;
                app.ConeEditField.Enable=S.interfaz.ConeEditField.Enable;app.ConeEditField.Value=S.interfaz.ConeEditField.Value;
                % He asignado polos, puedo representar cosas
                app.pushbutton_plot3dpointsppal.Enable=S.interfaz.pushbutton_plot3dpointsppal.Enable;
                app.pushbutton_plotstereopolesppal.Enable=S.interfaz.pushbutton_plotstereopolesppal.Enable;
                               
                % 5 Análisis clúster
                % Si tengo en análisis cluster encendido, es que he
                % asignado los polos y puedo hacer análisis estadístico
                app.UITable_clusters.Enable=S.interfaz.UITable_clusters.Enable;app.UITable_clusters.Data=S.interfaz.UITable_clusters.Data;
                app.ksigmasEditFieldLabel.Enable=S.interfaz.ksigmasEditFieldLabel.Enable;
                app.ksigmasEditField.Enable=S.interfaz.ksigmasEditField.Enable;app.ksigmasEditField.Value=S.interfaz.ksigmasEditField.Value;
                app.FixnormalvectorCheckBox.Enable=S.interfaz.FixnormalvectorCheckBox.Enable;app.FixnormalvectorCheckBox.Value=S.interfaz.FixnormalvectorCheckBox.Value;
                app.DBSCAN.Enable=S.interfaz.Button_DBSCAN.Enable;
                app.DBSCAN_edit.Enable=S.interfaz.DBSCAN_edit.Enable;
                app.Clusters_merge.Enable=S.interfaz.Clusters_merge.Enable;
                app.Clusters_restore.Enable=S.interfaz.Clusters_restore.Enable;

                % Menú de botones para exportar resultados
                app.Button_Save_database.Enable=S.interfaz.Button_Save_database.Enable;
                app.Button_Save_XYZJs.Enable=S.interfaz.Button_Save_XYZJs.Enable;
                app.Button_Export_Alldata.Enable=S.interfaz.Button_Save_results.Enable;
                
                % Ventana del log del log
                % app.ConsoleListBoxLabel.Enable=S.interfaz.ConsoleListBoxLabel.Enable;
                app.LogListBox.Enable=S.interfaz.LogListBox.Enable;app.LogListBox.Items=S.interfaz.LogListBox.Items;
               
                % guardo la información en el log
                tiempo=floor(toc);
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - Database recovered: ',pathname,filename, 'Total time: ',num2str(tiempo), ' seconds.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
        end

        % Menu selected function: menu_saveddbb
        function menu_saveddbbMenuSelected(app, event)
            save_database(app);
        end

        % Button pushed function: Button_Save_database
        function Button_Save_databasePushed(app, event)
            save_database(app);
        end

        % Menu selected function: NormalspacingMenu
        function NormalspacingMenuSelected(app, event)
            gui_normalspacing;
        end

        % Menu selected function: PersistenceMenu
        function PersistenceMenuSelected(app, event)
            gui_persistence;
        end

        % Button pushed function: Button_Export_Alldata
        function Button_Export_AlldataPushed(app, event)


            tic

            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end

            % Se genera un reporte con los datos
            [npoints,~]=size(app.data.P);
            if isfield(app.data,'npb')
                knn = app.data.npb;
            else
                app.data.npb=0;
                knn=0;
            end
            if isfield(app.data,'tolerancia')
                tolerancia = app.data.tolerancia;
            else
                app.data.tolerancia=0;
                tolerancia=0;
            end

            nsec = app.data.nsec;
            angulovpples = app.data.angulovpples;
            cone = app.data.cone;
            vnfamilia = app.data.vnfamilia;
            if isfield(app.data,'ksigmas')
                ksigmas = app.data.ksigmas;
            else
                ksigmas = app.ksigmasEditField.Value;
            end

            [nfamilias,~] = size(app.data.planos_pples); % calculada al principio de este callback
            [ ~ ] = f_report_v02( pathname, filename, npoints, knn, tolerancia, nsec, angulovpples, cone, vnfamilia, ksigmas, nfamilias, app.data.planos_pples, app.data.familia_cluster_plano);

            %guardamos las coordenadas, familia y clúster
            A=app.data.puntos_familia_cluster;
            [~]=f_savematrix([pathname,filename, ' xyz-js-c.txt'], A);

            %guardamos la familia clúster parámetros del plano
            A=app.data.familia_cluster_plano;
            [~] = f_savematrix([pathname,filename, ' js-c-abcd.txt'],A);

            % guardamos todas las coordenadas con todos los datos
            A=app.data.puntos_familia_cluster;
            [np,~]=size(A); % número de puntos en el point cloud
            B=app.data.familia_cluster_plano;
            C=zeros(np,9); C(:,1:5)=A(:,1:5);
            jointset=unique(B(:,1));
            [njs,~]=size(jointset);
            for i=1:njs
                clusters=unique(B(B(:,1)==jointset(i),2));
                [ncs,~]=size(clusters);
                for j=1:ncs
                    I=find(C(:,4)==jointset(i) & C(:,5)==clusters(j));
                    C(I,6)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),4);
                    C(I,7)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),5);
                    C(I,8)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),6);
                    C(I,9)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),7);
                end
            end
            [~] = f_savematrix([pathname,filename, ' xyz-js-c-abcd.txt'],C);

            % genero la salida XYZRGBNxNyNz
            %             D(:,7:9)=C(:,6:8);
            %             [~] = f_savematrix([pathname,filename,' XYZ-RGB-NxNyNz.txt'],D);

            % genero la salida Dipdir_Dip_Density
            % planos_pples=app.data.planos_pples; % no lo cargo porque se ha
            % cargado al principio
            [~] = f_savematrix([pathname,filename,' Dipdir-Dip-Density.txt'],app.data.planos_pples);

            % guardo la información en el log
            tiempo=floor(toc);
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Saved all the results to text files; total time: ', num2str(tiempo),' seconds.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;

            % Y ya puestos, guardo el log en un log.txt
            f = fopen([pathname,filename,' - log.txt'], 'wt');
            % [n,~]=size(S);
            n=length(S);
            for i=1:n
                fprintf(f, '%s \n', S{1,i});
            end
            fclose(f);
        end

        % Close request function: DiscontinuitySetExtractor3UIFigure
        function DiscontinuitySetExtractor3UIFigureCloseRequest(app, event)
            % Borro las ventanas auxiliares, por si acaso
            delete(app.DialogApp);
            delete(app);
        end

        % Callback function: PushTool_Clear
        function PushTool_ClearClicked(app, event)
            clear_gui(app);
        end

        % Callback function: PushTool_Load3DP
        function PushTool_Load3DPClicked(app, event)
            menu_load3DPCSelected(app);
        end

        % Callback function: PushTool_SaveProject
        function PushTool_SaveProjectClicked(app, event)
            save_database(app);
        end

        % Callback function: PushTool_LoadProject
        function PushTool_LoadProjectClicked(app, event)
            menu_loadddbMenuSelected(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Button pushed function: coloreapuntospornormales
        function coloreapuntospornormalesButtonPushed(app, event)
            coloreapuntosconnormales(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DiscontinuitySetExtractor3UIFigure = uifigure('Visible', 'off');
            app.DiscontinuitySetExtractor3UIFigure.Name = 'Discontinuity Set Extractor 3.02';
            app.DiscontinuitySetExtractor3UIFigure.Icon = fullfile(pathToMLAPP, 'img', 'DSE_captura_03.png');
            app.DiscontinuitySetExtractor3UIFigure.CloseRequestFcn = createCallbackFcn(app, @DiscontinuitySetExtractor3UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DiscontinuitySetExtractor3UIFigure);
            app.FileMenu.Text = 'File';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Tooltip = {'Clear all data and start a new project'};
            app.ClearMenu.Text = 'Clear';
            app.menu_loadxyz = uimenu(app.FileMenu);
            app.menu_loadxyz.MenuSelectedFcn = createCallbackFcn(app, @menu_load3DPCSelected, true);
            app.menu_loadxyz.Tooltip = {'Load the point cloud in ASCII (.txt) or binary (.ply). Note that .ply file can load normals.'};
            app.menu_loadxyz.Text = 'Load 3DPC';
            app.menu_loadddb = uimenu(app.FileMenu);
            app.menu_loadddb.MenuSelectedFcn = createCallbackFcn(app, @menu_loadddbMenuSelected, true);
            app.menu_loadddb.Tooltip = {'Load a project'};
            app.menu_loadddb.Text = 'Load database.m';
            app.menu_saveddbb = uimenu(app.FileMenu);
            app.menu_saveddbb.MenuSelectedFcn = createCallbackFcn(app, @menu_saveddbbMenuSelected, true);
            app.menu_saveddbb.Tooltip = {'Save the current project'};
            app.menu_saveddbb.Enable = 'off';
            app.menu_saveddbb.Text = 'Save database.m';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.DiscontinuitySetExtractor3UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.NormalspacingMenu = uimenu(app.ToolsMenu);
            app.NormalspacingMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalspacingMenuSelected, true);
            app.NormalspacingMenu.Text = 'Normal spacing';
            app.PersistenceMenu = uimenu(app.ToolsMenu);
            app.PersistenceMenu.MenuSelectedFcn = createCallbackFcn(app, @PersistenceMenuSelected, true);
            app.PersistenceMenu.Text = 'Persistence';
            app.HelpMenu = uimenu(app.DiscontinuitySetExtractor3UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.DiscontinuitySetExtractor3UIFigure);
            app.PushTool_Clear = uipushtool(app.Toolbar);
            app.PushTool_Clear.Tooltip = {'Clear memory and GUI'};
            app.PushTool_Clear.ClickedCallback = createCallbackFcn(app, @PushTool_ClearClicked, true);
            app.PushTool_Clear.Icon = fullfile(pathToMLAPP, 'img', 'fa-trash-o.png');
            app.PushTool_Load3DP = uipushtool(app.Toolbar);
            app.PushTool_Load3DP.Tooltip = {'Load a ''txt'' or ''ply'' 3DPC'};
            app.PushTool_Load3DP.ClickedCallback = createCallbackFcn(app, @PushTool_Load3DPClicked, true);
            app.PushTool_Load3DP.Icon = fullfile(pathToMLAPP, 'img', 'fa-file-o.png');
            app.PushTool_LoadProject = uipushtool(app.Toolbar);
            app.PushTool_LoadProject.Tooltip = {'Load an existing project'};
            app.PushTool_LoadProject.ClickedCallback = createCallbackFcn(app, @PushTool_LoadProjectClicked, true);
            app.PushTool_LoadProject.Icon = fullfile(pathToMLAPP, 'img', 'fa-folder-open-o.png');
            app.PushTool_SaveProject = uipushtool(app.Toolbar);
            app.PushTool_SaveProject.Tooltip = {'Save the current project'};
            app.PushTool_SaveProject.ClickedCallback = createCallbackFcn(app, @PushTool_SaveProjectClicked, true);
            app.PushTool_SaveProject.Icon = fullfile(pathToMLAPP, 'img', 'fa-save.png');
            app.Normals_Panel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.Normals_Panel.Tooltip = {'Calculate the normals for every point using the knn nearest neighbours and perform the coplanarity test.'};
            app.Normals_Panel.Title = 'Normal vectors and poles';
            app.pushbutton_2setupplanes = uibutton(app.Normals_Panel, 'push');
            app.pushbutton_2setupplanes.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_2setupplanesButtonPushed, true);
            app.pushbutton_2setupplanes.Icon = 'imagen_vnormal.png';
            app.pushbutton_2setupplanes.Enable = 'off';
            app.pushbutton_2setupplanes.Tooltip = {'Calculate normals with knn neighbours and the coplanarity test.'};
            app.pushbutton_2setupplanes.Text = '';
            app.knnEditFieldLabel = uilabel(app.Normals_Panel);
            app.knnEditFieldLabel.Enable = 'off';
            app.knnEditFieldLabel.Text = 'knn';
            app.box_nneighbours = uieditfield(app.Normals_Panel, 'text');
            app.box_nneighbours.Enable = 'off';
            app.box_nneighbours.Value = '30';
            app.CoplanaritytestSwitch = uiswitch(app.Normals_Panel, 'rocker');
            app.CoplanaritytestSwitch.ValueChangedFcn = createCallbackFcn(app, @CoplanaritytestSwitchValueChanged, true);
            app.CoplanaritytestSwitch.Enable = 'off';
            app.CoplanaritytestSwitch.Tooltip = {'Switch on to perform the coplanarity test when the normals are calculated. When switched off, if the Computer Vision Toolbox is installed, the calculation is faster.'};
            app.toleranceEditFieldLabel = uilabel(app.Normals_Panel);
            app.toleranceEditFieldLabel.Enable = 'off';
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.box_tolerancia = uieditfield(app.Normals_Panel, 'text');
            app.box_tolerancia.Enable = 'off';
            app.box_tolerancia.Value = '0.2';
            app.HSVCheckBox = uicheckbox(app.Normals_Panel);
            app.HSVCheckBox.Enable = 'off';
            app.HSVCheckBox.Tooltip = {'Enable the calculation of the HSV Colour Map for the points normals'};
            app.HSVCheckBox.Text = 'HSV';
            app.CoplanaritytestSwitchLabel = uilabel(app.Normals_Panel);
            app.CoplanaritytestSwitchLabel.Enable = 'off';
            app.CoplanaritytestSwitchLabel.Text = {'Coplanarity'; 'test'};
            app.coloreapuntospornormales = uibutton(app.Normals_Panel, 'push');
            app.coloreapuntospornormales.ButtonPushedFcn = createCallbackFcn(app, @coloreapuntospornormalesButtonPushed, true);
            app.coloreapuntospornormales.Icon = fullfile(pathToMLAPP, 'imagen_HSV.png');
            app.coloreapuntospornormales.Enable = 'off';
            app.coloreapuntospornormales.Tooltip = {'Calculate normals with knn neighbours and the coplanarity test.'};
            app.coloreapuntospornormales.Text = '';
            app.AssignaDStoeachpointPanel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.AssignaDStoeachpointPanel.Title = 'Assign a DS to each point';
            app.Button_assign_ppalpoles = uibutton(app.AssignaDStoeachpointPanel, 'push');
            app.Button_assign_ppalpoles.ButtonPushedFcn = createCallbackFcn(app, @Button_assign_ppalpolesPushed, true);
            app.Button_assign_ppalpoles.Icon = 'imagen_cono.png';
            app.Button_assign_ppalpoles.Enable = 'off';
            app.Button_assign_ppalpoles.Tooltip = {'Assign a DS to a point if its normal vector is within the cone. Note that the axis of the cone corresponds with the normal vector of the DS.'};
            app.Button_assign_ppalpoles.Text = '';
            app.ConeEditFieldLabel = uilabel(app.AssignaDStoeachpointPanel);
            app.ConeEditFieldLabel.Enable = 'off';
            app.ConeEditFieldLabel.Text = 'Cone';
            app.ConeEditField = uieditfield(app.AssignaDStoeachpointPanel, 'numeric');
            app.ConeEditField.Enable = 'off';
            app.ConeEditField.Tooltip = {'Maximum angle between the normal vector of a point and of the discontinuity set.'};
            app.ConeEditField.Value = 30;
            app.Plots_Panel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.Plots_Panel.Title = 'Plot';
            app.pushbutton_plot3dpoints = uibutton(app.Plots_Panel, 'push');
            app.pushbutton_plot3dpoints.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot3dpointsButtonPushed, true);
            app.pushbutton_plot3dpoints.Icon = 'imagen_3DPC.png';
            app.pushbutton_plot3dpoints.Enable = 'off';
            app.pushbutton_plot3dpoints.Tooltip = {'Plot the 3DPC'};
            app.pushbutton_plot3dpoints.Text = '';
            app.pushbutton_plotstereopoles = uibutton(app.Plots_Panel, 'push');
            app.pushbutton_plotstereopoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotstereopolesButtonPushed, true);
            app.pushbutton_plotstereopoles.Icon = 'imagen_stereonet_poles.png';
            app.pushbutton_plotstereopoles.Enable = 'off';
            app.pushbutton_plotstereopoles.Tooltip = {'Plot all the poles in a stereonet'};
            app.pushbutton_plotstereopoles.Text = '';
            app.pushbutton_plotdensitypoles = uibutton(app.Plots_Panel, 'push');
            app.pushbutton_plotdensitypoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotdensitypolesButtonPushed, true);
            app.pushbutton_plotdensitypoles.Icon = 'imagen_stereonet.png';
            app.pushbutton_plotdensitypoles.Enable = 'off';
            app.pushbutton_plotdensitypoles.Tooltip = {'Plot the density of poles'};
            app.pushbutton_plotdensitypoles.Text = '';
            app.pushbutton_plotstereopolesppal = uibutton(app.Plots_Panel, 'push');
            app.pushbutton_plotstereopolesppal.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotstereopolesppalButtonPushed, true);
            app.pushbutton_plotstereopolesppal.Icon = 'imagen_stereonet_poles_classified.png';
            app.pushbutton_plotstereopolesppal.Enable = 'off';
            app.pushbutton_plotstereopolesppal.Tooltip = {'Plot the poles. Colours depend on the assigned DS.'};
            app.pushbutton_plotstereopolesppal.Text = '';
            app.pushbutton_plot3dpointsppal = uibutton(app.Plots_Panel, 'push');
            app.pushbutton_plot3dpointsppal.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot3dpointsppalButtonPushed, true);
            app.pushbutton_plot3dpointsppal.Icon = 'imagen_3DPC_clasificada.png';
            app.pushbutton_plot3dpointsppal.Enable = 'off';
            app.pushbutton_plot3dpointsppal.Tooltip = {'View the 3DPC classified according the selected DS.'};
            app.pushbutton_plot3dpointsppal.Text = '';
            app.ClusteranalysisPanel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.ClusteranalysisPanel.Title = 'Cluster analysis';
            app.DBSCAN = uibutton(app.ClusteranalysisPanel, 'push');
            app.DBSCAN.ButtonPushedFcn = createCallbackFcn(app, @DBSCANPushed, true);
            app.DBSCAN.Icon = 'imagen_DBSCAN.png';
            app.DBSCAN.Enable = 'off';
            app.DBSCAN.Tooltip = {'Find the clusters of each DS using the DBSCAB algorithm. This figure was downloaded from Wikipedia.'};
            app.DBSCAN.Text = '';
            app.FixnormalvectorCheckBox = uicheckbox(app.ClusteranalysisPanel);
            app.FixnormalvectorCheckBox.Enable = 'off';
            app.FixnormalvectorCheckBox.Text = 'Fix normal vector';
            app.FixnormalvectorCheckBox.Value = true;
            app.DBSCAN_edit = uibutton(app.ClusteranalysisPanel, 'push');
            app.DBSCAN_edit.ButtonPushedFcn = createCallbackFcn(app, @DBSCAN_editPushed, true);
            app.DBSCAN_edit.Icon = 'imagen_DBSCAN_clean.png';
            app.DBSCAN_edit.Enable = 'off';
            app.DBSCAN_edit.Tooltip = {'Edit the clusters, removing those clusters with less than a certain number of points.'};
            app.DBSCAN_edit.Text = '';
            app.Clusters_merge = uibutton(app.ClusteranalysisPanel, 'push');
            app.Clusters_merge.ButtonPushedFcn = createCallbackFcn(app, @Clusters_mergePushed, true);
            app.Clusters_merge.Icon = 'imagen_merge_clusters.png';
            app.Clusters_merge.Enable = 'off';
            app.Clusters_merge.Tooltip = {'Merge the coplanar clusters according to a coplanarity test. The test is managed by the parameter k. Values of 2 are normal.'};
            app.Clusters_merge.Text = '';
            app.Clusters_restore = uibutton(app.ClusteranalysisPanel, 'push');
            app.Clusters_restore.ButtonPushedFcn = createCallbackFcn(app, @Clusters_restoreButtonPushed, true);
            app.Clusters_restore.Icon = 'imagen_DBSCAN_restore.png';
            app.Clusters_restore.Enable = 'off';
            app.Clusters_restore.Tooltip = {'Restore the clusters to the initial situation.'};
            app.Clusters_restore.Text = '';
            app.ksigmasEditFieldLabel = uilabel(app.ClusteranalysisPanel);
            app.ksigmasEditFieldLabel.Enable = 'off';
            app.ksigmasEditFieldLabel.Text = 'k sigmas';
            app.ksigmasEditField = uieditfield(app.ClusteranalysisPanel, 'numeric');
            app.ksigmasEditField.Enable = 'off';
            app.ksigmasEditField.Value = 2;
            app.UITable_clusters = uitable(app.ClusteranalysisPanel);
            app.UITable_clusters.ColumnName = {'DS'; 'cl'; 'n'; 'A'; 'B'; 'C'; 'D_'; 'sigma'};
            app.UITable_clusters.RowName = {};
            app.UITable_clusters.ColumnSortable = [true true true false false false true false];
            app.UITable_clusters.Tooltip = {'Discontinuity sets and clusters of the classified point cloud. ABCD are the componentes of the plane equation: Ax+By+Cz+D=0. The standard deviation is the distance points of a cluster - best fit plane of that cluster. D_=D-mean(D).'};
            app.UITable_clusters.Enable = 'off';
            app.DensityandprincipalpolesextractionPanel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.DensityandprincipalpolesextractionPanel.Title = 'Density and principal poles extraction';
            app.pushbutton_3statanalysis = uibutton(app.DensityandprincipalpolesextractionPanel, 'push');
            app.pushbutton_3statanalysis.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_3statanalysisButtonPushed, true);
            app.pushbutton_3statanalysis.Icon = 'imagen_stereonet.png';
            app.pushbutton_3statanalysis.Enable = 'off';
            app.pushbutton_3statanalysis.Tooltip = {'Calculate the probability density function of the normals'' poles.'};
            app.pushbutton_3statanalysis.Text = '';
            app.BinslevelSpinnerLabel = uilabel(app.DensityandprincipalpolesextractionPanel);
            app.BinslevelSpinnerLabel.Enable = 'off';
            app.BinslevelSpinnerLabel.Text = 'Bins level';
            app.BinslevelSpinner = uispinner(app.DensityandprincipalpolesextractionPanel);
            app.BinslevelSpinner.ValueChangingFcn = createCallbackFcn(app, @BinslevelSpinnerValueChanging, true);
            app.BinslevelSpinner.ValueChangedFcn = createCallbackFcn(app, @BinslevelSpinnerValueChanged, true);
            app.BinslevelSpinner.Enable = 'off';
            app.BinslevelSpinner.Tooltip = {'Level of the Bins. The number of bins is 2^(level). Recommended values are 6 or 7.'};
            app.BinslevelSpinner.Value = 6;
            app.MinangleEditFieldLabel = uilabel(app.DensityandprincipalpolesextractionPanel);
            app.MinangleEditFieldLabel.Enable = 'off';
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.DensityandprincipalpolesextractionPanel, 'numeric');
            app.MinangleEditField.Enable = 'off';
            app.MinangleEditField.Tooltip = {'Minimum angle between normals of the principal discontinuity sets.'};
            app.MinangleEditField.Value = 30;
            app.pushbutton_31editppalpoles = uibutton(app.DensityandprincipalpolesextractionPanel, 'push');
            app.pushbutton_31editppalpoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_31editppalpolesButtonPushed, true);
            app.pushbutton_31editppalpoles.Icon = 'imagen_stereonet_edit.png';
            app.pushbutton_31editppalpoles.Enable = 'off';
            app.pushbutton_31editppalpoles.Tooltip = {'Edit the principal poles'};
            app.pushbutton_31editppalpoles.Text = '';
            app.NmaxDSEditFieldLabel = uilabel(app.DensityandprincipalpolesextractionPanel);
            app.NmaxDSEditFieldLabel.Enable = 'off';
            app.NmaxDSEditFieldLabel.Text = 'Nmax DS';
            app.NmaxDSEditField = uieditfield(app.DensityandprincipalpolesextractionPanel, 'numeric');
            app.NmaxDSEditField.Enable = 'off';
            app.NmaxDSEditField.Tooltip = {'Maximun number of principal planes to extract from the density function'};
            app.NmaxDSEditField.Value = 10;
            app.UITable_principalplanes = uitable(app.DensityandprincipalpolesextractionPanel);
            app.UITable_principalplanes.ColumnName = {'Dip Dir [º]'; 'Dip [º]'; 'Density'; '%'};
            app.UITable_principalplanes.RowName = {};
            app.UITable_principalplanes.Enable = 'off';
            app.SavefilesPanel = uipanel(app.DiscontinuitySetExtractor3UIFigure);
            app.SavefilesPanel.Title = 'Save files';
            app.Button_Save_XYZJs = uibutton(app.SavefilesPanel, 'push');
            app.Button_Save_XYZJs.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_XYZJsPushed, true);
            app.Button_Save_XYZJs.Icon = 'imagen_3DPC_clasificada_export.png';
            app.Button_Save_XYZJs.Enable = 'off';
            app.Button_Save_XYZJs.Tooltip = {'Export XYZ-Js (early classification), before the cluster analysis'};
            app.Button_Save_XYZJs.Text = '';
            app.Button_Save_database = uibutton(app.SavefilesPanel, 'push');
            app.Button_Save_database.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_databasePushed, true);
            app.Button_Save_database.Icon = 'imagen_icons8-save-50.png';
            app.Button_Save_database.Enable = 'off';
            app.Button_Save_database.Tooltip = {'Save the the current state of the process. Exports a .m file with all the info.'};
            app.Button_Save_database.Text = '';
            app.Button_Export_Alldata = uibutton(app.SavefilesPanel, 'push');
            app.Button_Export_Alldata.ButtonPushedFcn = createCallbackFcn(app, @Button_Export_AlldataPushed, true);
            app.Button_Export_Alldata.Icon = 'imagen_export.png';
            app.Button_Export_Alldata.Enable = 'off';
            app.Button_Export_Alldata.Tooltip = {'Export all the results in ASCII files to the current folder.'};
            app.Button_Export_Alldata.Text = '';
            app.LogListBoxLabel = uilabel(app.DiscontinuitySetExtractor3UIFigure);
            app.LogListBoxLabel.Text = 'Log';
            app.LogListBox = uilistbox(app.DiscontinuitySetExtractor3UIFigure);
            app.LogListBox.Items = {'Greetings Professor Falken', 'Wellcome to Discontinuity Set Extractor v3.02', 'How about a nice game of chess?'};
            app.LogListBox.Value = 'Greetings Professor Falken';
            % Show the figure after all components are created
            app.DiscontinuitySetExtractor3UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSE_v302

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiscontinuitySetExtractor3UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiscontinuitySetExtractor3UIFigure)
        end
    end
end