classdef gui_clusterseditor_v03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSEClusterseditorUIFigure     matlab.ui.Figure
        SaveExitButton                matlab.ui.control.Button
        ClusterEditorremoveclusterssmallerthanathresholdsizeLabel  matlab.ui.control.Label
        RestoreButton                 matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        MaxsizeEditField              matlab.ui.control.NumericEditField
        MaxsizeEditFieldLabel         matlab.ui.control.Label
        DiscontinuitySetSpinner       matlab.ui.control.Spinner
        DiscontinuitySetSpinnerLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp; 
        data % String en el que guardo los datos que tengo
        databackup % Description

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            % About: Esta GUI es para adaptar este editor a la nueva app designer
            % 21 diciembre 2022
            
            app.CallingApp=caller;

            % En este Startup, toda la información la tengo en datos
            % lo paso todo al objeto app.data, y trabajo sobre este
            % al final, mando a la pantalla principal el app.data
            app.data.puntos_familia_cluster=datos.puntos_familia_cluster;
            app.data.familia_cluster_plano=datos.familia_cluster_plano;
            app.data.puntos_familia_cluster_fullclusters=datos.puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano_fullclusters=datos.familia_cluster_plano_fullclusters;
            app.data.polos_estereoppalasignados_afterdbscan=datos.polos_estereoppalasignados_afterdbscan;

            % Preparo el slider
            app.data.familias=unique(app.data.familia_cluster_plano(:,1));
            app.data.nds=length(app.data.familias);
            app.DiscontinuitySetSpinner.Limits=[0 app.data.nds];
            % Guardo los valores en la tabla
            app.UITable.Data=app.data.familia_cluster_plano(:,1:3);
            % Represento todos los clusters, por defecto
            bar(app.UIAxes,app.data.familia_cluster_plano(:,1:3));

            % Antes de terminar, tengo que guardar todos los datos en el
            % backup
            app.databackup=app.data;

        end

        % Value changed function: DiscontinuitySetSpinner
        function DiscontinuitySetSpinnerValueChanged(app, event)
            ds_id = app.DiscontinuitySetSpinner.Value;
            if ds_id==0 
                % todas las familias
                % Guardo los valores en la tabla
                app.UITable.Data=app.data.familia_cluster_plano(:,1:3);
                % Represento todos los clusters, por defecto
                bar(app.UIAxes,app.data.familia_cluster_plano(:,1:3));
            else
                % sólo la familia seleccionada
                I=find(app.data.familia_cluster_plano(:,1)==app.data.familias(ds_id));
                % Guardo los valores en la tabla
                app.UITable.Data=app.data.familia_cluster_plano(I,1:3);
                % Represento todos los clusters, por defecto
                bar(app.UIAxes,app.data.familia_cluster_plano(I,1:3));                
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ds = app.DiscontinuitySetSpinner.Value;
            % Para limipiar tengo esta función: f_clusterclear_v02
            % [ puntos_familia_cluster, familia_cluster_plano, polos_estereoppalasignados_limpio ] = f_clusterclear_v02( puntos_familia_cluster_fullclusters, familia_cluster_plano_fullclusters , ds, ppcluster, polos_estereoppalasignados_fullclusters)
            app.data.ppcluster=app.MaxsizeEditField.Value;
            % Preparo la familia, porque puede ser un 0...
            if ds==0
                familia=0;
            else
                familia=app.data.familias(ds);
            end
            [ app.data.puntos_familia_cluster, ...
                app.data.familia_cluster_plano, ...
                app.data.polos_estereoppalasignados_afterdbscan_limpio ] = ...
            f_clusterclear_v02( app.data.puntos_familia_cluster_fullclusters, ...
                app.data.familia_cluster_plano_fullclusters , ...
                familia, ...
                app.data.ppcluster, ...
                app.data.polos_estereoppalasignados_afterdbscan);
            if ds==0
                % todas las familias
                % Guardo los valores en la tabla
                app.UITable.Data=app.data.familia_cluster_plano(:,1:3);
                % Represento todos los clusters, por defecto
                bar(app.UIAxes,app.data.familia_cluster_plano(:,1:3));
            else
                % sólo la familia seleccionada
                I=find(app.data.familia_cluster_plano(:,1)==app.data.familias(ds));
                % Guardo los valores en la tabla
                app.UITable.Data=app.data.familia_cluster_plano(I,1:3);
                % Represento todos los clusters, por defecto
                bar(app.UIAxes,app.data.familia_cluster_plano(I,1:3));
            end
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.data=app.databackup;
            % Preparo el slider
            app.data.familias=unique(app.data.familia_cluster_plano(:,1));
            app.data.nds=length(app.data.familias);
            app.DiscontinuitySetSpinner.Limits=[0 app.data.nds];
            % Guardo los valores en la tabla
            app.UITable.Data=app.data.familia_cluster_plano(:,1:3);
            % Represento todos los clusters, por defecto
            bar(app.UIAxes,app.data.familia_cluster_plano(:,1:3));
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            % llamo a la función principal, que es el DSE
            updatefunctionclusters(app.CallingApp,app.data);
            delete(app);
        end

        % Close request function: DSEClusterseditorUIFigure
        function DSEClusterseditorUIFigureCloseRequest(app, event)
            % Esto es lo que se ejecuta cuando se cierra la ventana
            app.CallingApp.DiscontinuitySetExtractor3UIFigure.Visible="on"; % Ya muestro de nuevo el DSE       
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DSEClusterseditorUIFigure = uifigure('Visible', 'off');
            colormap(app.DSEClusterseditorUIFigure, 'parula');
            app.DSEClusterseditorUIFigure.Name = 'DSE Clusters'' editor';
            app.DSEClusterseditorUIFigure.Icon = fullfile(pathToMLAPP, 'imagen_DBSCAN_clean.png');
            app.DSEClusterseditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @DSEClusterseditorUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.DSEClusterseditorUIFigure);
            title(app.UIAxes, 'Number of points per cluster')
            xlabel(app.UIAxes, 'Cluster')
            ylabel(app.UIAxes, 'N')
            zlabel(app.UIAxes, 'Z')
            app.DiscontinuitySetSpinnerLabel = uilabel(app.DSEClusterseditorUIFigure);
            app.DiscontinuitySetSpinnerLabel.Text = 'Discontinuity Set';
            app.DiscontinuitySetSpinner = uispinner(app.DSEClusterseditorUIFigure);
            app.DiscontinuitySetSpinner.ValueChangedFcn = createCallbackFcn(app, @DiscontinuitySetSpinnerValueChanged, true);
            app.MaxsizeEditFieldLabel = uilabel(app.DSEClusterseditorUIFigure);
            app.MaxsizeEditFieldLabel.Text = 'Max size';
            app.MaxsizeEditField = uieditfield(app.DSEClusterseditorUIFigure, 'numeric');
            app.MaxsizeEditField.Value = 100;
            app.UITable = uitable(app.DSEClusterseditorUIFigure);
            app.UITable.ColumnName = {'DSet'; 'Cluster'; 'Size'};
            app.UITable.RowName = {};
            app.ApplyButton = uibutton(app.DSEClusterseditorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RestoreButton = uibutton(app.DSEClusterseditorUIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.ClusterEditorremoveclusterssmallerthanathresholdsizeLabel = uilabel(app.DSEClusterseditorUIFigure);
            app.ClusterEditorremoveclusterssmallerthanathresholdsizeLabel.Text = 'Cluster Editor: remove clusters smaller than a threshold size';
            app.SaveExitButton = uibutton(app.DSEClusterseditorUIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            % Show the figure after all components are created
            app.DSEClusterseditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_clusterseditor_v03(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSEClusterseditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSEClusterseditorUIFigure)
        end
    end
end