classdef gui_ppalpoleseditor_v07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditorofprincipalpolesUIFigure  matlab.ui.Figure
        TituloFigura                    matlab.ui.control.Label
        PolesDSButton                   matlab.ui.control.StateButton
        PolesButton                     matlab.ui.control.StateButton
        DensityButton                   matlab.ui.control.StateButton
        Label_Percentage                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ConeEditField                   matlab.ui.control.NumericEditField
        ConeEditFieldLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Insert                          matlab.ui.control.Image
        MoveUp                          matlab.ui.control.Image
        MoveDown                        matlab.ui.control.Image
        Delete                          matlab.ui.control.Image
        Restart                         matlab.ui.control.Image
        DSEditField                     matlab.ui.control.NumericEditField
        DSEditFieldLabel                matlab.ui.control.Label
        SaveExitButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Es la función que llama a esta ventana, me permite identificarla cuando al final llame al updatefunction
        data % donde voy a guardar todos mis datos durant este programa
        % Los datos que recibo son:
        % datos.ppal_poles % los polos pples, en dip dir y dip
        % datos.polos_pples_cart % los polos pples en XY
        % datos.polos_estereo_cartesianas % Son todos los polos en XY
        % datos.P la matriz de puntos
		% La densidad, tampoco la pasaría porque creo que es mejor calcularla
        % datos.X
        % datos.Y
        % datos.density
    end
    
    methods (Access = private)
        % Estas son las funciones que voy a utilizar en la GUI

        function results = plotdensity(app)
            % Función que representa la función de densidad con los nuevos polos principales
            results=0;

            % Primero, tomo los datos
            % polos_estereo_cartesianas = app.data.polos_estereo_cartesianas;
            polos_pples = app.data.ppal_poles;
            polos_pples_cart=polos_pples;
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            % P=app.data.P;
            % ppal_poles=app.data.ppal_poles;
            % representamos la salida
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=polos_pples_cart(1:kk,3)'+0.1;
            end
            % maxd=max(max(density)); mind=min(min(density));
            numberisolines=80; % número de isoloníneas
            % isolines=(maxd-mind)/numberisolines;

            cla(app.UIAxes,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes);
            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes,'on')

            % opción 3D
            % app.UIAxes.Color=[1 1 1];

            % Pongo un punto donde está cada polo principal
            scatter3(app.UIAxes,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3),tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Dibujo las isolineas
            contour3(app.UIAxes,X,Y,density,numberisolines);
            axis (app.UIAxes,[-1 1 -1 1]); axis(app.UIAxes,'square');
            % Doy formato a la gráfica
            app.TituloFigura.Text=['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'];
            xlabel(app.UIAxes,'axis X'); ylabel(app.UIAxes,'axis Y');

            % preparo el puntero
            % dcm = datacursormode(gcf);
            % datacursormode on;
            % set(dcm,'updatefcn',@myfunction)

            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes,'off')
        end
        
        function results = falsillariquelme(app,figura)
            results=0;
            % Riquelme -- Program for plotting a stereonet for DSE
            % Written by Adrian Riquelme, September 2016
            % ariquelme@ua.es
            % Department of Civil Engineering, University of Alicante, Spain

            % First, we define the circle and the axis
            % Code extracted from the wulff net
            N = 50;
            cx = cos(0:pi/N:2*pi);                           % points on circle
            cy = sin(0:pi/N:2*pi);
            xh = [-1 1];                                     % horizontal axis
            yh = [0 0];
            xv = [0 0];                                      % vertical axis
            yv = [-1 1];
            plot(figura,xh,yh,'-.k',xv,yv,'-.k');                     %plot green axes
            % axis('square');
            % axis off;
            hold(figura,"on")
            plot(figura,cx,cy,'--k');                                %plot white circle
            % axis(figura,[-1 1 -1 1],'square','off');
            axis(figura,[-1 1 -1 1],'square','off');

            % Now, we draw the circles that refer to the dip angle
            psi = [0:pi/N:2*pi];
            beta=[15 30 45 60 75 90]/180*pi;
            [~,n]=size(beta);
            for i = 1:(n-1)                                   % plot dip circles
                [radio]=f_radio(beta(i));
                x = radio .* sin(psi);
                y = radio .* cos(psi);
                plot(figura,x,y,':r');
                text(figura,radio,0,num2str(beta(i)*180/pi),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            end
            % Inserto el label del buzamiento de 90
            [radio]=f_radio(90*pi/180);
            text(figura,radio,0,num2str(90),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);

            % axis(figura,'square','off');
            axis(figura,'square');

            % Finally, we draw the circles that refer to the dip direction
            alpha0 = 0:pi/6:(2*pi-pi/12);
            alpha = pi/2 - alpha0+pi;
            etiqueta = 0:360/12:(330);
            x = 1.2*cos(alpha);
            y = 1.2*sin(alpha);
            [~,n]=size(alpha);
            tamanyoangulos=12;
            for i=1:n
                % le pongo su etiqueta el ángulo respecto al norte
                if etiqueta(i)==0 || etiqueta(i)==180
                    text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','center','VerticalAlignment','cap','FontSize',tamanyoangulos);
                else
                    if etiqueta(i)>0 && etiqueta(i)<180
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Right','FontSize',tamanyoangulos);
                    else
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Left','FontSize',tamanyoangulos);
                    end
                end       
                
                xaux=[f_radio(15*pi/180)*cos(alpha(i))  cos(alpha(i))];
                yaux=[f_radio(15*pi/180)*sin(alpha(i))  sin(alpha(i))];
                plot(figura,xaux,yaux,'r:.');
                % pongo una pequeña línea para marcar dónde va ese label
                xaux=[cos(alpha(i))  1.05*cos(alpha(i))];
                yaux=[sin(alpha(i))  1.05*sin(alpha(i))];
                plot(figura,xaux,yaux,'k-');
                
            end
            hold(figura,"off")
        end
        
        function results = plotpoles(app)
            results=0;
            % Preparo los datos
            % polos_estereo_cartesianas = app.data.polos_estereo_cartesianas;
            polos_pples = app.data.ppal_poles;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            polos_pples_cart=polos_pples;
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );


            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(app.UIAxes,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes);

            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes,'on')

            % app.UITable.Color=[1 1 1];
            % axis(app.UITable,'square','equal');
            z=zeros(size(polos_estereo_cartesianas(:,1)));
            z(:,1)=0; % originalmente, -0.1
            % Dibujo todos los polos
            scatter3(app.UIAxes,polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),z,1);

            % Pongo un punto donde está cada polo principal.
            % Empiezo preparando coordenadas de los polos pples
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                % xc=polos_pples_cart(1:kk,3)'+0.1;
                xc(1:kk)=0.1;
            end
            scatter3(app.UIAxes,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),0 ,tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_estereo_cartesianas)),' points'];
            
            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes,'off')
        end
        
        function results = plotpolesds(app)
            results=0;

            % Preparo los datos
            cone=app.ConeEditField.Value/180*pi;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            polos_pples=app.data.ppal_poles;
            polos_pples_cart=zeros(size(polos_pples));
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );
            app.data.polos_pples_cart=polos_pples_cart;
            P=app.data.P;
            [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, polos_estereo_cartesianas, polos_pples_cart ,P);
            A=polos_estereoppalasignados;
            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(app.UIAxes,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes);
            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes,'on');
            
            % Dibujo los polos según familias
            scatter(app.UIAxes,A(:,1),A(:,2),1,A(:,3));
            % colormap(app.UIAxes,jet);
            colormap(app.UIAxes,"turbo");

            % Pongo un punto donde está cada polo principal.
            % Empiezo preparando coordenadas de los polos pples
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=polos_pples_cart(1:kk,3)'+0.1;
            end
            scatter3(app.UIAxes,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3),tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection Assigned Principal Poles: ',num2str(length(A)),' points'];

            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes,'off');

            % preparo el puntero
            %             dcm = datacursormode(gcf);
            %             datacursormode on;
            %             set(dcm,'updatefcn',@myupdatefcn)
            
        end
        
        function results = UpdateTable(app)
            results=0;
            % Esta función actualiza el % de puntos que representan los
            % polos principales
            
            % Preparo las variables
            cone = app.ConeEditField.Value/180*pi;
            polos_estereo_cartesianas = app.data.polos_estereo_cartesianas;
            polos_pples = app.data.ppal_poles;
            polos_pples_cart=polos_pples;
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );
            P=app.data.P;
            ppal_poles=app.data.ppal_poles;

            % Asignamos cada punto a su polo principal
            [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, polos_estereo_cartesianas, polos_pples_cart ,P);
            [n,~]=size(P); % número de puntos totales
            [nf,~]=size(ppal_poles); % número de familias que hay
            for i=1:nf
                % calculo el % de puntos que hay y lo pongo en la cuarta columna
                [npuntos,~]=size(polos_estereoppalasignados(polos_estereoppalasignados(:,3)==i));
                ppal_poles(i,4)=npuntos/n*100;
            end
            % Calculo el total de puntos asignados a los polos
            totalpoints=sum(ppal_poles(:,4));
            app.Label_Percentage.Text=num2str(totalpoints);
            % set(app.data.text_totalpercent,'String',['Total %: ',num2str(totalpoints)]);
            % actualizo la tabla de salida
            app.UITable.Data=ppal_poles;
            % set(app.data.uitable_ppalpoles,'Enable','on','Data',ppal_poles);
            app.data.ppal_poles=ppal_poles; % Guardo la edición
            
        end
        
        function results = plot(app)
            results=0;
            % Función que representa lo que esté seleccionado
            
            % Voy a mantener el punto de vista de la gráfica
            % en primer lugar, si está activada la densidad, pillo la vista
            % para mantenerla
            View=app.UIAxes.View;
            Projection=app.UIAxes.Projection;
            CameraPosition=app.UIAxes.CameraPosition;
            CameraPositionMode=app.UIAxes.CameraPositionMode;
            CameraTarget=app.UIAxes.CameraTarget;
            CameraTargetMode=app.UIAxes.CameraTargetMode;
            CameraUpVector=app.UIAxes.CameraUpVector;
            CameraUpVectorMode=app.UIAxes.CameraUpVectorMode;
            CameraViewAngle=app.UIAxes.CameraViewAngle;
            CameraViewAngleMode=app.UIAxes.CameraViewAngleMode;
            
            if app.PolesDSButton.Value==1
                plotpolesds(app);
            else
                if app.PolesButton.Value==1
                    plotpoles(app);
                else
                    plotdensity(app);
                end
            end

            % Finalmente, restituyo el punto de vista
            app.UIAxes.View=View;
            app.UIAxes.Projection=Projection;
            app.UIAxes.CameraPosition=CameraPosition;
            app.UIAxes.CameraPositionMode=CameraPositionMode;
            app.UIAxes.CameraTarget=CameraTarget;
            app.UIAxes.CameraTargetMode=CameraTargetMode;
            app.UIAxes.CameraUpVector=CameraUpVector;
            app.UIAxes.CameraUpVectorMode=CameraUpVectorMode;
            app.UIAxes.CameraViewAngle=CameraViewAngle;
            app.UIAxes.CameraViewAngleMode=CameraViewAngleMode;
        end
        
        function results = UpdateTableDS(app,ds)
            results=0;
            % todo los datos del ds que acabo de modificar y actualizo la densidad, tabla y
            % representación
            
            % calculo la densidad del nuevo punto
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            xq=app.UITable.Data(ds,1);
            yq=app.UITable.Data(ds,2);
            [xq, yq]=f_clar2cart(xq,yq);
            zq=griddata(X,Y,density,xq,yq);
            app.UITable.Data(ds,3)=zq;
            % guardar la tabla
            app.data.ppal_poles=app.UITable.Data;
            % Actualizo la tabla
            % El porcentaje de asignación se calcula ahí dentro
            UpdateTable(app);
            % representar el estereograma que toque
            plot(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            app.CallingApp=caller;

            % Toda la información la tengo en datos, la tengo que pasar a
            % app.data y trabajar con esta estructura hasta el final

            app.data.ppal_poles=datos.ppal_poles; % esta cambiará durante la ejecución del programa
            app.data.polos_pples_cart=datos.polos_pples_cart; % esta cambiará durante la ejecución del programa
            app.data.X=datos.X;
            app.data.Y=datos.Y;
            app.data.density=datos.density;
            app.data.polos_estereo_cartesianas=datos.polos_estereo_cartesianas;
            app.data.P=datos.P;

            % Preparo un backup de los polos principales para la función de
            % restaurar
            app.data.backup.ppal_poles=datos.ppal_poles; % esta cambiará durante la ejecución del programa
            app.data.backup.polos_pples_cart=datos.polos_pples_cart; % esta cambiará durante la ejecución del programa

            % Represento la función
            plotdensity(app);
            % Actualizo la tabla 
            UpdateTable(app);

        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            updatefunctioneditppalpoles(app.CallingApp,app.data);
            delete(app);
        end

        % Close request function: EditorofprincipalpolesUIFigure
        function EditorofprincipalpolesUIFigureCloseRequest(app, event)
            % Cuando cierro la ventana sin guardar, activo de nuevo la
            % ventana principal y elimino esta
            app.CallingApp.DiscontinuitySetExtractor3UIFigure.Visible="on";
            delete(app)          
        end

        % Value changed function: ConeEditField
        function ConeEditFieldValueChanged(app, event)
            value = app.ConeEditField.Value;
            % compruebo que no estoy fuera de los límites
            % [nds,~]=size(app.data.ppal_poles);
            if value<1 ||  value>90
                uialert(app.EditorofprincipalpolesUIFigure,'The cone angle must be a value between 0 and 90 degrees.','Invalid Angle Value');
            else
                % El valor es correcto y no tengo que hacer nada
                % como ha cambiado el ángulo del cono, actualizo el plot
                plot(app);
                % También actualizo la tabla para el porcentaje de puntos
                % asignado
                UpdateTable(app);
            end
            

        end

        % Value changed function: PolesDSButton
        function PolesDSButtonValueChanged(app, event)
            value = app.PolesDSButton.Value;
            if value==1
                app.DensityButton.Value=0;
                app.PolesButton.Value=0;
                plotpolesds(app);
            else
            end
        end

        % Value changed function: DensityButton
        function DensityButtonValueChanged(app, event)
            value = app.DensityButton.Value;
            if value==1
                app.PolesDSButton.Value=0;
                app.PolesButton.Value=0;
                plotdensity(app);
            else
            end
        end

        % Value changed function: PolesButton
        function PolesButtonValueChanged(app, event)
            value = app.PolesButton.Value;
            if value==1
                app.PolesDSButton.Value=0;
                app.DensityButton.Value=0;
                plotpoles(app);
            else
            end
        end

        % Value changed function: DSEditField
        function DSEditFieldValueChanged(app, event)
            value = app.DSEditField.Value;
            % Qué hacer cuando cambiamos este valor manualmente o
            % automáticamente
            ppal_poles=app.data.ppal_poles; % los polos principales, como la tabla
            % compruebo que no estoy fuera de los límites
            [nds,~]=size(ppal_poles);
            if value<1 || value>nds
                uialert(app.EditorofprincipalpolesUIFigure,{'The selected principal pole is invalid'},'Input error','Icon','warning');
                app.DSEditField.Value=1;
            else
                % No tengo que hacer nada
            end
        end

        % Image clicked function: Insert
        function InsertImageClicked(app, event)
            % Tengo que insertar un nuevo polo principal
            ppal_poles=app.data.ppal_poles; % los polos principales, como la tabla
            % Tomo el número máximo de polos principales
            [nds,~]=size(ppal_poles);
            nuevods=app.DSEditField.Value;
            % Inserto en esa posición una nueva fila
            ppal_poles(nuevods+1:nds+1,:)=ppal_poles(nuevods:nds,:);
            % La nueva fila la establezco en el cero
            ppal_poles(nuevods,:)=0;
            % Guardo los datos
            app.UITable.Data=ppal_poles;
            % Actualizo la tabla con la densidad nueva y represento la figura
            UpdateTableDS(app, nuevods);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % he seleccionado una celda
            ds=indices(1);
            app.DSEditField.Value=ds;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % En primer lugar, compruebo que los valores que se han metido
            % son válidos
            switch indices(2)
                case 1
                    % es dirección de buzamiento
                    if newData<0 || newData>360
                        uialert(app.EditorofprincipalpolesUIFigure,{'The dip direction angle value must be between 0 and 360 degrees'},'Input error','Icon','warning');
                    else
                        % Está bien, actualizo todo
                        ds=indices(1);
                        UpdateTableDS(app,ds);
                    end
                case 2
                    % es dirección de buzamiento
                    if newData<0 || newData>90
                        uialert(app.EditorofprincipalpolesUIFigure,{'The dip angle value must be between 0 and 90 degrees'},'Input error','Icon','warning');
                    else
                        % Está bien, actualizo todo
                        ds=indices(1);
                        UpdateTableDS(app,ds);
                    end
                otherwise

            end
        end

        % Image clicked function: Delete
        function DeleteImageClicked(app, event)
            % elimino el polo principal seleccionado
            ppal_poles=app.UITable.Data; % los polos principales, como la tabla
            % Tomo el número máximo de polos principales
            [nds,~]=size(ppal_poles);
            if nds==1
                % no puedo quitarlo, al menos debe de haber 1
                uialert(app.EditorofprincipalpolesUIFigure,{'At least there must be a principal pole'},'Error','Icon','warning');
            else
                % sí que puedo
            dsborrar=app.DSEditField.Value;
            % Inserto en esa posición una nueva fila
            ppal_poles(dsborrar:nds-1,:)=ppal_poles(dsborrar+1:nds,:);
            ppal_poles(nds,:)=[]; % elimino la última fila
            % si el dsborrar es igual al número de polos principales, debo
            % de actualizar el polo principal seleccionado quitándole uno
            if dsborrar==nds
                app.DSEditField.Value=dsborrar-1;
            end
            % Guardo los datos
            app.data.ppal_poles=ppal_poles;
            % Actualizo la tabla y represento la figura
            UpdateTable(app); plot(app);
            end

        end

        % Image clicked function: Restart
        function RestartImageClicked(app, event)
            % Restauro los polos principales originales
            app.data.ppal_poles=app.data.backup.ppal_poles;
            app.data.polos_pples_cart=app.data.backup.polos_pples_cart;
            % Actualizo la tabla 
            UpdateTable(app);
            % Represento la función
            plotdensity(app);

        end

        % Image clicked function: MoveUp
        function MoveUpImageClicked(app, event)
            % Tomo el DS seleccionado y lo muevo una posición arriba
            % Sólo puedo hacerlo si el DS es mayor o igual a 2
            ds=app.DSEditField.Value;
            if ds>1
                % puedorrrr
                app.UITable.Data([ds ds-1],:)=app.UITable.Data([ds-1 ds],:);
                % actualizo la selección del ds
                app.DSEditField.Value=ds-1;
            else
                % no puedorrrrr
                uialert(app.EditorofprincipalpolesUIFigure,{'The selection is 1, I cannot move it up'},'Error','Icon','warning');
            end
            UpdateTableDS(app, ds);
        end

        % Image clicked function: MoveDown
        function MoveDownImageClicked(app, event)
            % Tomo el DS seleccionado y lo muevo una posición arriba
            % Sólo puedo hacerlo si el DS es menor que la dimensión
            % máxima
            ds=app.DSEditField.Value;
            [nds,~]=size(app.UITable.Data);
            if ds<nds
                % puedorrrr
                app.UITable.Data([ds ds+1],:)=app.UITable.Data([ds+1 ds],:);
                % actualizo la selección del ds
                app.DSEditField.Value=ds+1;
            else
                % no puedorrrrr
                uialert(app.EditorofprincipalpolesUIFigure,{['The selection is ',num2str(ds),', I cannot move it down']},'Error','Icon','warning');
            end
            UpdateTableDS(app, ds);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EditorofprincipalpolesUIFigure = uifigure('Visible', 'off');
            app.EditorofprincipalpolesUIFigure.Name = 'Editor of principal poles';
            app.EditorofprincipalpolesUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditorofprincipalpolesUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.EditorofprincipalpolesUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveExitButton = uibutton(app.EditorofprincipalpolesUIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.DSEditFieldLabel = uilabel(app.EditorofprincipalpolesUIFigure);
            app.DSEditFieldLabel.Text = 'DS';
            app.DSEditField = uieditfield(app.EditorofprincipalpolesUIFigure, 'numeric');
            app.DSEditField.ValueChangedFcn = createCallbackFcn(app, @DSEditFieldValueChanged, true);
            app.DSEditField.Value = 1;
            app.Restart = uiimage(app.EditorofprincipalpolesUIFigure);
            app.Restart.ImageClickedFcn = createCallbackFcn(app, @RestartImageClicked, true);
            app.Restart.ImageSource = fullfile(pathToMLAPP, 'img', 'imagen_restart.png');
            app.Delete = uiimage(app.EditorofprincipalpolesUIFigure);
            app.Delete.ImageClickedFcn = createCallbackFcn(app, @DeleteImageClicked, true);
            app.Delete.ImageSource = fullfile(pathToMLAPP, 'img', 'imagen_delete.png');
            app.MoveDown = uiimage(app.EditorofprincipalpolesUIFigure);
            app.MoveDown.ImageClickedFcn = createCallbackFcn(app, @MoveDownImageClicked, true);
            app.MoveDown.ImageSource = fullfile(pathToMLAPP, 'img', 'arrow._downpng.png');
            app.MoveUp = uiimage(app.EditorofprincipalpolesUIFigure);
            app.MoveUp.ImageClickedFcn = createCallbackFcn(app, @MoveUpImageClicked, true);
            app.MoveUp.ImageSource = fullfile(pathToMLAPP, 'img', 'arrow_up.png');
            app.Insert = uiimage(app.EditorofprincipalpolesUIFigure);
            app.Insert.ImageClickedFcn = createCallbackFcn(app, @InsertImageClicked, true);
            app.Insert.Tooltip = {'Insert a new principal pole'};
            app.Insert.ImageSource = fullfile(pathToMLAPP, 'img', 'imagen_insert.png');
            app.UITable = uitable(app.EditorofprincipalpolesUIFigure);
            app.UITable.ColumnName = {'Dip Dir º'; 'Dip'; 'Density'; '%'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ConeEditFieldLabel = uilabel(app.EditorofprincipalpolesUIFigure);
            app.ConeEditFieldLabel.Text = 'Cone [º]';
            app.ConeEditField = uieditfield(app.EditorofprincipalpolesUIFigure, 'numeric');
            app.ConeEditField.ValueChangedFcn = createCallbackFcn(app, @ConeEditFieldValueChanged, true);
            app.ConeEditField.Value = 30;
            app.Label = uilabel(app.EditorofprincipalpolesUIFigure);
            app.Label.Text = '%:';
            app.Label_Percentage = uilabel(app.EditorofprincipalpolesUIFigure);
            app.Label_Percentage.Text = '0';
            app.DensityButton = uibutton(app.EditorofprincipalpolesUIFigure, 'state');
            app.DensityButton.ValueChangedFcn = createCallbackFcn(app, @DensityButtonValueChanged, true);
            app.DensityButton.Text = 'Density';
            app.DensityButton.Value = true;
            app.PolesButton = uibutton(app.EditorofprincipalpolesUIFigure, 'state');
            app.PolesButton.ValueChangedFcn = createCallbackFcn(app, @PolesButtonValueChanged, true);
            app.PolesButton.Text = 'Poles';
            app.PolesDSButton = uibutton(app.EditorofprincipalpolesUIFigure, 'state');
            app.PolesDSButton.ValueChangedFcn = createCallbackFcn(app, @PolesDSButtonValueChanged, true);
            app.PolesDSButton.Text = 'Ploles DS';
            app.TituloFigura = uilabel(app.EditorofprincipalpolesUIFigure);
            app.TituloFigura.Text = '';
            % Show the figure after all components are created
            app.EditorofprincipalpolesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_ppalpoleseditor_v07(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditorofprincipalpolesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditorofprincipalpolesUIFigure)
        end
    end
end