classdef gui_ppalpoleseditor_v3_07_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        uitable_ppalpoles        matlab.ui.control.Table
        DStomoveSpinnerLabel     matlab.ui.control.Label
        DStomoveSpinner          matlab.ui.control.Spinner
        Button_3                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        SelectaDSEditFieldLabel  matlab.ui.control.Label
        SelectaDSEditField       matlab.ui.control.EditField
        Button                   matlab.ui.control.StateButton
        Button_2                 matlab.ui.control.StateButton
        MovetoEditFieldLabel     matlab.ui.control.Label
        MovetoEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % Estas son las variables internas que voy a utilizar para el cálculo    
        polos_estereo_cartesianas;
        ppal_poles;
        polos_pples_cart;
        X;
        Y;
        density;
        P;
    end
    
    methods (Access = private)
        
        
        function results = plot(app)
            results=0;
            % Lo primero reseteo la figura
            cla(app.UIAxes,'reset');
            % le pongo el fondo en blanco
            app.UIAxes.BackgroundColor=[1 1 1];
            % Función que representa la función de densidad con los nuevos polos principales
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.UIAxes,'on');
            N = 50;
            cx = cos(0:pi/N:2*pi);                           % points on circle
            cy = sin(0:pi/N:2*pi);
            xh = [-1 1];                                     % horizontal axis
            yh = [0 0];
            xv = [0 0];                                      % vertical axis
            yv = [-1 1];
            axis(app.UIAxes,[-1 1 -1 1]);
            axis(app.UIAxes,'square');
            plot(app.UIAxes,xh,yh,'-.k',xv,yv,'-.k');                     %plot green axes
            axis(app.UIAxes,'off');
            plot(app.UIAxes,cx,cy,'--k');                                %plot white circle
            % Now, we draw the circles that refer to the dip angle
            psi = [0:pi/N:2*pi];
            beta=[15 30 45 60 75 90]/180*pi;
            [~,n]=size(beta);
            for i = 1:(n-1)                                   % plot dip circles
                [radio]=f_radio(beta(i));
                x = radio .* sin(psi);
                y = radio .* cos(psi);
                plot(app.UIAxes,x,y,':r');
                text(app.UIAxes,radio,0,num2str(beta(i)*180/pi),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            end
            % Inserto el label del buzamiento de 90
            [radio]=f_radio(90*pi/180);
            text(app.UIAxes,radio,0,num2str(90),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            axis(app.UIAxes,'square');
            % Finally, we draw the circles that refer to the dip direction
            alpha0 = 0:pi/6:(2*pi-pi/12);
            alpha = pi/2 - alpha0+pi;
            etiqueta = 0:360/12:(330);
            x = 1.2*cos(alpha);
            y = 1.2*sin(alpha);
            [~,n]=size(alpha);
            tamanyoangulos=12;
            for i=1:n
                % le pongo su etiqueta el ángulo respecto al norte
                if etiqueta(i)==0 || etiqueta(i)==180
                    text(app.UIAxes,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','center','VerticalAlignment','cap','FontSize',tamanyoangulos);
                else
                    if etiqueta(i)>0 && etiqueta(i)<180
                        text(app.UIAxes,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Right','FontSize',tamanyoangulos);
                    else
                        text(app.UIAxes,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Left','FontSize',tamanyoangulos);
                    end
                end
                % Pongo una línea radial para marcar la dirección del polo
                %     xaux=[-cos(alpha(i))  cos(alpha(i))];
                %     yaux=[-sin(alpha(i))  sin(alpha(i))];
                %     plot(xaux,yaux,'r:.');
                xaux=[f_radio(15*pi/180)*cos(alpha(i))  cos(alpha(i))];
                yaux=[f_radio(15*pi/180)*sin(alpha(i))  sin(alpha(i))];
                % pongo una pequeña línea para marcar dónde va ese label
                xaux=[cos(alpha(i))  1.05*cos(alpha(i))];
                yaux=[sin(alpha(i))  1.05*sin(alpha(i))];
                plot(app.UIAxes,xaux,yaux,'k-');
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Lo primero es pillar los polos principales de la tabla
           [ app.polos_pples_cart(:,1),app.polos_pples_cart(:,2) ] = f_clar2cart( app.ppal_poles(:,1),app.ppal_poles(:,2) );
            % representamos la salida
            [kk, nc]=size(app.polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=app.polos_pples_cart(1:kk,1)'+0.1;
            xb=app.polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=app.polos_pples_cart(1:kk,3)'+0.1;
            end
            numberisolines=80; % número de isoloníneas
            % cla(app.UIAxes,'reset'); % limpiamos las figuras existentes
            scatter3(app.UIAxes,app.polos_pples_cart(1:kk,1),app.polos_pples_cart(1:kk,2),app.polos_pples_cart(1:kk,3),tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            title(app.UIAxes,['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%']);
            % hold on; 
            contour3(app.UIAxes,app.X,app.Y,app.density,numberisolines);
            for ii=1:kk
                text(app.UIAxes,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end
        end
        
        function results = GuardaGlobales(app)
            results=0;
            setappdata(0,'global_ppal_poles',app.ppal_poles);
            setappdata(0,'global_polos_pples_cart',app.polos_pples_cart);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.uitable_ppalpoles.Data=getappdata(0,'global_ppal_poles');
            % guardamos las variables en las handles
            app.polos_estereo_cartesianas=getappdata(0,'polos_estereo_cartesianas');
            app.ppal_poles=getappdata(0,'global_ppal_poles');
            app.polos_pples_cart=getappdata(0,'global_polos_pples_cart');
            app.X=getappdata(0,'global_X');
            app.Y=getappdata(0,'global_Y');
            app.density=getappdata(0,'global_density');
            app.P=getappdata(0,'P');
            plot(app); % Represento los datos que hay al iniciar esta gui
        end

        % Cell edit callback: uitable_ppalpoles
        function uitable_ppalpolesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            pos1=indices(1);
            pos2=indices(2);
            if pos2~=3 % Para que no se seleccione el % sino el dip dir o el dip
                app.ppal_poles(pos1,pos2)=newData;
                % calculo la densidad del nuevo punto
                xq=app.ppal_poles(pos1,1);
                yq=app.ppal_poles(pos1,2);
                [xq, yq]=f_clar2cart(xq,yq);
                zq=griddata(app.X,app.Y,app.density,xq,yq);
                app.ppal_poles(pos1,3)=zq;
                app.uitable_ppalpoles.Data=app.ppal_poles;
                % Finalizo y guardo
                plot(app);
                GuardaGlobales(app);
            end
        end

        % Cell selection callback: uitable_ppalpoles
        function uitable_ppalpolesCellSelection(app, event)
            indices = event.Indices;
            app.DStomoveSpinner.Value=indices(1);
            
            set(app.box_ppalpole2move,'Value',num2str(pos1));
            set(app.box_pole2remove,'Value',num2str(pos1));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            xq=0;
            yq=0;
            zq=griddata(app.X,app.Y,app.density,xq,yq);
            % inicio el vector del nuevo polo. Cuidado, le añado la cuarta componente
            % correspondiente al porcentaje de puntos del nuevo polo respecto al total.
            % Se pone en cero, más adelante se calcula.
            v=[xq yq zq 0];
            app.ppal_poles=[app.ppal_poles;v];
            % configuro el entorno para la nueva dimensión
            app.uitable_ppalpoles.Data=app.ppal_poles;
            % Finalizo y guardo
            plot(app);
            GuardaGlobales(app);
        end

        % Value changed function: DStomoveSpinner
        function DStomoveSpinnerValueChanged(app, event)
            value = app.DStomoveSpinner.Value;
            PreviousValue = event.PreviousValue;
            try
                [n,~]=size(app.ppal_poles);
                % preparo los índices de los polos que quiero mover
                ppalpole2move=value;
                if ppalpole2move~=n
                    ppalpolemove2=ppalpole2move+(changingValue-PreviousValue); % este sumando dice si sube o baja
                    [app.ppal_poles]=swap(app.ppal_poles,1,ppalpole2move,ppalpolemove2);
                    % cambio los boxes: no hace falta porque es un spinner y
                    % cambia sólo
                    % set(app.box_ppalpole2move,'Value',num2str(ppalpolemove2));
                    % pongo el resultado en la tabla
                    app.uitable_ppalpoles.Data=app.ppal_poles;
                    set(app.uitable_ppalpoles,'Enable','on','Data',app.ppal_poles);
                    % guardo la variable en las handles
                    % Finalizo y guardo
                    plot(app);
                    GuardaGlobales(app);
                end
            catch
                % Error
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.uitable_ppalpoles = uitable(app.UIFigure);
            app.uitable_ppalpoles.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.uitable_ppalpoles.RowName = {};
            app.uitable_ppalpoles.CellEditCallback = createCallbackFcn(app, @uitable_ppalpolesCellEdit, true);
            app.uitable_ppalpoles.CellSelectionCallback = createCallbackFcn(app, @uitable_ppalpolesCellSelection, true);
            app.DStomoveSpinnerLabel = uilabel(app.UIFigure);
            app.DStomoveSpinnerLabel.Text = 'DS to move';
            app.DStomoveSpinner = uispinner(app.UIFigure);
            app.DStomoveSpinner.Limits = [1 Inf];
            app.DStomoveSpinner.ValueChangedFcn = createCallbackFcn(app, @DStomoveSpinnerValueChanged, true);
            app.DStomoveSpinner.Value = 1;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'imagen_delete.png';
            app.Button_3.Tooltip = {'Remove the selected DS'};
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Icon = 'imagen_restart.png';
            app.Button_5.Tooltip = {'Restart'};
            app.Button_5.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'imagen_insert.png';
            app.Button_4.Tooltip = {'Add a new DS on the selected position'};
            app.Button_4.Text = '';
            app.SelectaDSEditFieldLabel = uilabel(app.UIFigure);
            app.SelectaDSEditFieldLabel.Text = 'Select a DS';
            app.SelectaDSEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Icon = 'arrow_up.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Icon = 'arrow._down.png';
            app.Button_2.Text = '';
            app.MovetoEditFieldLabel = uilabel(app.UIFigure);
            app.MovetoEditFieldLabel.Text = 'Move to';
            app.MovetoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_ppalpoleseditor_v3_07_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end