classdef gui_smrestadistico_v01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticSMRToolUIFigure    matlab.ui.Figure
        AnglesoftheslopePanel       matlab.ui.container.Panel
        DipDirectionEditFieldLabel  matlab.ui.control.Label
        DipDirectionEditField       matlab.ui.control.NumericEditField
        DipEditFieldLabel           matlab.ui.control.Label
        DipEditField                matlab.ui.control.NumericEditField
        PhaseofpolesButtonGroup     matlab.ui.container.ButtonGroup
        AfternormalsButton          matlab.ui.control.ToggleButton
        AfterDSassignementButton    matlab.ui.control.ToggleButton
        AfterclusteranalysisButton  matlab.ui.control.ToggleButton
        AfterclustersremovalButton  matlab.ui.control.ToggleButton
        DSSpinnerLabel              matlab.ui.control.Label
        DSSpinner                   matlab.ui.control.Spinner
        CalculateButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Estas son las variables internas que voy a utilizar para el cálculo
        smrestadistico; % struct donde meto los polos del dse
        polos; % Es la matriz donde van todos los polos, con la familia al final
        % en esta varible se ha seleccionado a qué fase corresponden los
        % polos
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Tomo los datos que vienen del DSE
            app.smrestadistico=getappdata(0,'smrestadistico');
            % if isfield(app.smrestadistico,'planos_pples')==1
            %     app.smrestadistico.planos_pples=getappdata(0,'planos_pples');
            % end
            % Activo los botones toggle para el tipo de polos
            if isfield(app.smrestadistico,'polos_estereo_cartesianas')==1
                app.AfternormalsButton.Enable='on';
            end
            if isfield(app.smrestadistico,'polos_estereoppalasignados')==1
                app.AfterDSassignementButton.Enable='on';
            end
            if isfield(app.smrestadistico,'polos_estereoppalasignados_afterdbscan')==1
                app.AfterclusteranalysisButton.Enable='on';
            end
            if isfield(app.smrestadistico,'polos_estereoppalasignados_afterdbscan_limpio')==1
                app.AfterclustersremovalButton.Enable='on';
            end
            % Como por defecto queda la opción de polos 1, dejo estos
            % polos:
            app.polos=app.smrestadistico.polos_estereo_cartesianas;
        end

        % Value changed function: DipDirectionEditField
        function DipDirectionEditFieldValueChanged(app, event)
            value = app.DipDirectionEditField.Value;
            if value < 0
                app.DipDirectionEditField.Value=0;
            end
            if value >360
                app.DipDirectionEditField.Value=0;
            end
        end

        % Value changed function: DipEditField
        function DipEditFieldValueChanged(app, event)
            value = app.DipEditField.Value;
            if value < 0
                app.DipDirectionEditField.Value=0;
            end
            if value >90
                app.DipDirectionEditField.Value=0;
            end
        end

        % Selection changed function: PhaseofpolesButtonGroup
        function PhaseofpolesButtonGroupSelectionChanged(app, event)
            selectedButton = app.PhaseofpolesButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '1. After normals'
                    app.polos=app.smrestadistico.polos_estereo_cartesianas;
                    app.DSSpinner.Enable='off';
                    app.DSSpinner.Limits=[-inf Inf];
                    app.DSSpinner.Value=0;
                case '2. After DS assignement'
                    app.polos=app.smrestadistico.polos_estereoppalasignados;
                    app.DSSpinner.Enable='on';
                    valor=app.DSSpinner.Value;
                    if valor>0 && valor<=max(unique(app.polos(:,3)))
                        app.DSSpinner.Value=valor;
                    else
                        app.DSSpinner.Value=1;
                    end
                    app.DSSpinner.Limits=[1 max(unique(app.polos(:,3)))];
                case '3. After cluster analysis'
                    app.polos=app.smrestadistico.polos_estereoppalasignados_afterdbscan;
                    app.DSSpinner.Enable='on';
                    valor=app.DSSpinner.Value;
                    if valor>0 && valor<=max(unique(app.polos(:,3)))
                        app.DSSpinner.Value=valor;
                    else
                        app.DSSpinner.Value=1;
                    end
                    app.DSSpinner.Limits=[1 max(unique(app.polos(:,3)))];
                case '4. After clusters removal'
                    app.polos=app.smrestadistico.polos_estereoppalasignados_afterdbscan_limpio;
                    app.DSSpinner.Enable='on';
                    valor=app.DSSpinner.Value;
                    if valor>0 && valor<=max(unique(app.polos(:,3)))
                        app.DSSpinner.Value=valor;
                    else
                        app.DSSpinner.Value=1;
                    end
                    app.DSSpinner.Limits=[1 max(unique(app.polos(:,3)))];
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            familia=app.DSSpinner.Value;
            if familia==0
                % calcular a lo bestia
                [alphaj, betaj]= f_polocart2dipdirdip_v02( app.polos(:,1),app.polos(:,2) );
                alphaj=alphaj*180/pi;
                betaj=betaj*180/pi;
                % asigno los datos del talud
                alphas=app.DipDirectionEditField.Value;
                betas=app.DipEditField.Value;
                % Dejo preparado los ángulos por si me hace falta más
                % adelante
                % calculo los ángulos auxiliares
                % [ Acoeff1 ] = f_Acoeff1( alphas, alphaj );
                % [ Ccoeff3 ] = f_Ccoeff3( alphas, alphaj, betas,betaj );
                % calculo los coeficientes
                [ coeff1 ] = f_coeff1( alphas, alphaj );
                [ coeff1tomas ] = f_coeff1tomas( alphas, alphaj);
                [ coeff2 ] = f_coeff2( alphas, alphaj, betaj );
                [ coeff2tomas ] = f_coeff2tomas( alphas, alphaj, betaj );
                [ coeff3 ] = f_coeff3( alphas, alphaj, betas, betaj );
                [ coeff3tomas ] = f_coeff3tomas( alphas, alphaj, betas, betaj );
                [ tiporotura ] = f_tiporotura( alphas, alphaj );
                F1F2F3Romana = coeff1.*coeff2.*coeff3;
                F1F2F3Tomas = coeff1tomas.*coeff2tomas.*coeff3tomas;
            else
                % tomar la familia y calcular
                I=find(app.polos(:,3)==familia);
                [alphaj, betaj]= f_polocart2dipdirdip_v02( app.polos(I,1),app.polos(I,2) );
                alphaj=alphaj*180/pi;
                betaj=betaj*180/pi;
                % asigno los datos del talud
                alphas=app.DipDirectionEditField.Value;
                betas=app.DipEditField.Value;
                % Dejo preparado los ángulos por si me hace falta más
                % adelante
                % calculo los ángulos auxiliares
                % [ Acoeff1 ] = f_Acoeff1( alphas, alphaj );
                % [ Ccoeff3 ] = f_Ccoeff3( alphas, alphaj, betas,betaj );
                % calculo los coeficientes
                [ coeff1 ] = f_coeff1( alphas, alphaj );
                [ coeff1tomas ] = f_coeff1tomas( alphas, alphaj);
                [ coeff2 ] = f_coeff2( alphas, alphaj, betaj );
                [ coeff2tomas ] = f_coeff2tomas( alphas, alphaj, betaj );
                [ coeff3 ] = f_coeff3( alphas, alphaj, betas, betaj );
                [ coeff3tomas ] = f_coeff3tomas( alphas, alphaj, betas, betaj );
                [ tiporotura ] = f_tiporotura( alphas, alphaj );
                F1F2F3Romana = coeff1.*coeff2.*coeff3;
                F1F2F3Tomas = coeff1tomas.*coeff2tomas.*coeff3tomas;
            end
            % Calculo los índices de las roturas planas y vuelco
            indices_plana=find(tiporotura(:,1)==1);
            indices_vuelco=find(tiporotura(:,1)==2);
            % Configuro el ancho del bin
            binwidth=0.1;
            % Represento los histogramas
            h=figure;

            % Meter orientación del talud y de la discontinuidad.
            if familia==0
                dipdir_ds=0;
                dip_ds=0;
            else
                dipdir_ds=app.smrestadistico.planos_pples(familia,1);
                dip_ds=app.smrestadistico.planos_pples(familia,2);
            end
            h.Name=['SMR coefficients of the DS:',num2str(familia),'. N: ',num2str(size(coeff1,1)),'. Slope: (',num2str(alphas,'%-5.2f'),'/',num2str(betas,'%-5.2f'),'); DS: (',num2str(dipdir_ds,'%-5.2f'),'/',num2str(dip_ds,'%-5.2f'),')'];
            % Fila 1: SMR Romana,
            % Columnas: F1, F2, F3, F1xF2xF3
            % F1
            subplot(2,4,1);
            roturaplana=coeff1(indices_plana);
            roturavuelco=coeff1(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[0 1]; h2.BinLimits=[0 1];
            h1.BinWidth=binwidth; h2.BinWidth=binwidth;
            g=gca;
            g.XLabel.String='F_1 coefficient (Romana)';
            % Ahora añado la leyenda
            legend('Planar','Toppling');
            
            
            % F2
            subplot(2,4,2);
            roturaplana=coeff2(indices_plana);
            roturavuelco=coeff2(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[0 1]; h2.BinLimits=[0 1];
            h1.BinWidth=binwidth; h2.BinWidth=binwidth;
            g=gca;
            g.XLabel.String='F_2 coefficient (Romana)';
            
            
            % F3
            subplot(2,4,3);
            roturaplana=coeff3(indices_plana);
            roturavuelco=coeff3(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[-60 0]; h2.BinLimits=[-60 0];
            h1.BinWidth=binwidth*60; h2.BinWidth=binwidth*60;
            g=gca;
            g.XLabel.String='F_3 coefficient (Romana)';
            
            % F1xF2xF3
            subplot(2,4,4);
            roturaplana=F1F2F3Romana(indices_plana);
            roturavuelco=F1F2F3Romana(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[-60 0]; h2.BinLimits=[-60 0];
            h1.BinWidth=binwidth*60; h2.BinWidth=binwidth*60;
            g=gca;
            g.XLabel.String='F_1\cdotF_2\cdotF_3 (Romana)';
            
            % Fila 2: SMR Tomás
            % Columnas: F1, F2, F3, F1xF2xF3
            % F1
            subplot(2,4,5);
            x=coeff1tomas;
            roturaplana=x(indices_plana);
            roturavuelco=x(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[0 1]; h2.BinLimits=[0 1];
            h1.BinWidth=binwidth; h2.BinWidth=binwidth;
            g=gca;
            g.XLabel.String='F_1 coefficient (Tomás)';
            hold off;
            
            % Fila 2: SMR Tomás
            % F2
            subplot(2,4,6);
            x=coeff2tomas;
            roturaplana=x(indices_plana);
            roturavuelco=x(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[0 1]; h2.BinLimits=[0 1];
            h1.BinWidth=binwidth; h2.BinWidth=binwidth;
            g=gca;
            g.XLabel.String='F_2 coefficient (Tomás)';
            hold off;
            
            % Fila 2: SMR Tomás
            % F3
            subplot(2,4,7);
            x=coeff3tomas;
            roturaplana=x(indices_plana);
            roturavuelco=x(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[-60 0]; h2.BinLimits=[-60 0];
            h1.BinWidth=binwidth*60; h2.BinWidth=binwidth*60;
            g=gca;
            g.XLabel.String='F_3 coefficient (Tomás)';
            hold off;
            
            % F1xF2xF3
            subplot(2,4,8);
            x=F1F2F3Tomas;
            roturaplana=x(indices_plana);
            roturavuelco=x(indices_vuelco);
            h1=histogram(roturaplana,'Normalization','probability'); hold on;
            h2=histogram(roturavuelco,'Normalization','probability'); hold off;
            h1.BinLimits=[-60 0]; h2.BinLimits=[-60 0];
            h1.BinWidth=binwidth*60; h2.BinWidth=binwidth*60;
            g=gca;
            g.XLabel.String='F_1\cdotF_2\cdotF_3 coefficient (Tomás)';
            hold off;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticSMRToolUIFigure = uifigure('Visible', 'off');
            app.StatisticSMRToolUIFigure.Name = 'Statistic SMRTool';
            app.AnglesoftheslopePanel = uipanel(app.StatisticSMRToolUIFigure);
            app.AnglesoftheslopePanel.Title = 'Angles of the slope';
            app.DipDirectionEditFieldLabel = uilabel(app.AnglesoftheslopePanel);
            app.DipDirectionEditFieldLabel.Text = 'Dip Direction [º]';
            app.DipDirectionEditField = uieditfield(app.AnglesoftheslopePanel, 'numeric');
            app.DipDirectionEditField.Limits = [0 360];
            app.DipDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @DipDirectionEditFieldValueChanged, true);
            app.DipDirectionEditField.Value = 135;
            app.DipEditFieldLabel = uilabel(app.AnglesoftheslopePanel);
            app.DipEditFieldLabel.Text = 'Dip  [º]';
            app.DipEditField = uieditfield(app.AnglesoftheslopePanel, 'numeric');
            app.DipEditField.Limits = [0 90];
            app.DipEditField.ValueChangedFcn = createCallbackFcn(app, @DipEditFieldValueChanged, true);
            app.DipEditField.Value = 45;
            app.PhaseofpolesButtonGroup = uibuttongroup(app.StatisticSMRToolUIFigure);
            app.PhaseofpolesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseofpolesButtonGroupSelectionChanged, true);
            app.PhaseofpolesButtonGroup.Title = 'Phase of poles';
            app.AfternormalsButton = uitogglebutton(app.PhaseofpolesButtonGroup);
            app.AfternormalsButton.Text = '1. After normals';
            app.AfternormalsButton.Value = true;
            app.AfterDSassignementButton = uitogglebutton(app.PhaseofpolesButtonGroup);
            app.AfterDSassignementButton.Enable = 'off';
            app.AfterDSassignementButton.Text = '2. After DS assignement';
            app.AfterclusteranalysisButton = uitogglebutton(app.PhaseofpolesButtonGroup);
            app.AfterclusteranalysisButton.Enable = 'off';
            app.AfterclusteranalysisButton.Text = '3. After cluster analysis';
            app.AfterclustersremovalButton = uitogglebutton(app.PhaseofpolesButtonGroup);
            app.AfterclustersremovalButton.Enable = 'off';
            app.AfterclustersremovalButton.Text = '4. After clusters removal';
            app.DSSpinnerLabel = uilabel(app.StatisticSMRToolUIFigure);
            app.DSSpinnerLabel.Tooltip = {''};
            app.DSSpinnerLabel.Text = 'DS ';
            app.DSSpinner = uispinner(app.StatisticSMRToolUIFigure);
            app.DSSpinner.Editable = 'off';
            app.DSSpinner.Enable = 'off';
            app.DSSpinner.Tooltip = {'Select the Discontinuity Set to proceed with the statistics calculation'};
            app.CalculateButton = uibutton(app.StatisticSMRToolUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tooltip = {'Calculate the density function of the SMR index factors.'};
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.StatisticSMRToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_smrestadistico_v01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticSMRToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticSMRToolUIFigure)
        end
    end
end